classdef MSpecMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSPECAppUIFigure                matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        NewProjectMenu                  matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        ContactUsMenu                   matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool3                       matlab.ui.container.toolbar.PushTool
        PushTool9                       matlab.ui.container.toolbar.PushTool
        PushTool8                       matlab.ui.container.toolbar.PushTool
        PushTool4                       matlab.ui.container.toolbar.PushTool
        PushTool7                       matlab.ui.container.toolbar.PushTool
        PushTool6                       matlab.ui.container.toolbar.PushTool
        ScoreTable                      matlab.ui.control.Table
        ScoreLabel                      matlab.ui.control.Label
        ClassNumberDropDown_2           matlab.ui.control.DropDown
        ClassNumberDropDown_2Label      matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        CreateNewMLPreprocessingProjectPanel_2  matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        ProjectTitleTab                 matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        ClassNumberSpinner              matlab.ui.control.Spinner
        ClassNumberSpinnerLabel         matlab.ui.control.Label
        DataTypesButtonGroup_4          matlab.ui.container.ButtonGroup
        Panel_5                         matlab.ui.container.Panel
        yaxisEditField_3                matlab.ui.control.EditField
        yaxisEditField_3Label           matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        xaxisEditField_3                matlab.ui.control.EditField
        xaxisEditField_3Label           matlab.ui.control.Label
        EditField_3                     matlab.ui.control.EditField
        otherButton_3                   matlab.ui.control.RadioButton
        UVVisibleButton_3               matlab.ui.control.RadioButton
        FTIRButton_3                    matlab.ui.control.RadioButton
        RamanSpectraButton_4            matlab.ui.control.RadioButton
        MassSpectraButton_3             matlab.ui.control.RadioButton
        DescriptionEditField_2          matlab.ui.control.TextArea
        DescriptionTextAreaLabel_3      matlab.ui.control.Label
        Next_CreateProjectButton_2      matlab.ui.control.Button
        ProjectNameEditField_2          matlab.ui.control.EditField
        ProjectNameEditField_2Label_2   matlab.ui.control.Label
        ImportDataTab                   matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        PleasefilltheclassnameLabel     matlab.ui.control.Label
        Import_CreateProjectButton_4    matlab.ui.control.Button
        DimensionLabel_3                matlab.ui.control.Label
        WidthField_3                    matlab.ui.control.NumericEditField
        xLabel_3                        matlab.ui.control.Label
        HeightField_3                   matlab.ui.control.NumericEditField
        NumberofMassSpectraEditField_3Label  matlab.ui.control.Label
        NumberofMassSpectraEditField_3  matlab.ui.control.NumericEditField
        ClassNameEditField_2            matlab.ui.control.EditField
        ClassNameEditField_2Label       matlab.ui.control.Label
        ImportStatusLabel_3             matlab.ui.control.Label
        Import_ImportButton_3           matlab.ui.control.Button
        ClassDropDown_2                 matlab.ui.control.DropDown
        ClassDropDown_2Label            matlab.ui.control.Label
        PurposeButtonGroup              matlab.ui.container.ButtonGroup
        DataforMLModelingButton         matlab.ui.control.RadioButton
        DataPreprocessingButton         matlab.ui.control.RadioButton
        Image2_2                        matlab.ui.control.Image
        RecentFilesPanel                matlab.ui.container.Panel
        OpenButton                      matlab.ui.control.Button
        RecentFileListBox               matlab.ui.control.ListBox
        CreateNewPreprocessingProjectPanel  matlab.ui.container.Panel
        DataTypesButtonGroup_3          matlab.ui.container.ButtonGroup
        Panel_4                         matlab.ui.container.Panel
        yaxisEditField_2                matlab.ui.control.EditField
        yaxisEditField_2Label           matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        xaxisEditField_2                matlab.ui.control.EditField
        xaxisEditField_2Label           matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        otherButton_2                   matlab.ui.control.RadioButton
        UVVisibleButton_2               matlab.ui.control.RadioButton
        FTIRButton_2                    matlab.ui.control.RadioButton
        RamanSpectraButton_3            matlab.ui.control.RadioButton
        MassSpectraButton_2             matlab.ui.control.RadioButton
        ImportStatusLabel               matlab.ui.control.Label
        Import_ImportButton             matlab.ui.control.Button
        DescriptionEditField            matlab.ui.control.TextArea
        DescriptionTextAreaLabel_2      matlab.ui.control.Label
        Import_CreateProjectButton      matlab.ui.control.Button
        NumberofMassSpectraEditField    matlab.ui.control.NumericEditField
        NumberofMassSpectraLabel        matlab.ui.control.Label
        HeightField                     matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        WidthField                      matlab.ui.control.NumericEditField
        DimensionLabel                  matlab.ui.control.Label
        ProjectNameEditField            matlab.ui.control.EditField
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        ImportyourspectraldatatobeginLabel  matlab.ui.control.Label
        WelcometoMSpecLabel             matlab.ui.control.Label
        PreprocessingTab                matlab.ui.container.Tab
        Preprocessing_SaveButton        matlab.ui.control.Button
        Preprocessing_ExportButton      matlab.ui.control.Button
        PlotPanel                       matlab.ui.container.Panel
        Preprocessing_PlotButton        matlab.ui.control.Button
        Preprocessing_EndingpointEditField  matlab.ui.control.EditField
        EndingpointEditFieldLabel       matlab.ui.control.Label
        Preprocessing_StartingpointEditField  matlab.ui.control.EditField
        StartingpointEditFieldLabel     matlab.ui.control.Label
        Preprocessing_SectionofinterestLabel  matlab.ui.control.Label
        Preprocessing_SpectrumtodisplayEditField  matlab.ui.control.EditField
        SpectrumtodisplayEditFieldLabel  matlab.ui.control.Label
        PeakAlignmentPanel              matlab.ui.container.Panel
        MaximumshiftallowedEditFieldLabel  matlab.ui.control.Label
        Preprocessing_MaximumshiftallowedEditField  matlab.ui.control.NumericEditField
        MinimumsegmentsizeallowedLabel  matlab.ui.control.Label
        Preprocessing_MinimumsegementsizeallowedEditField  matlab.ui.control.NumericEditField
        ReferenceSpectrumEditFieldLabel  matlab.ui.control.Label
        Preprocessing_ReferenceSpectrumEditField  matlab.ui.control.NumericEditField
        BaselineCorrectionPanel         matlab.ui.container.Panel
        QuantilevalueEditFieldLabel     matlab.ui.control.Label
        Preprocessing_QuantilevalueEditField  matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        Preprocessing_StepsizeEditField  matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        Preprocessing_WindowSizeEditField  matlab.ui.control.NumericEditField
        PreProcessingToolLabel          matlab.ui.control.Label
        Preprocessing_RawMSPlot         matlab.ui.control.UIAxes
        Preprocessing_PreprocessedMSPlot  matlab.ui.control.UIAxes
        NormalizationTab                matlab.ui.container.Tab
        DataNormalizationLabel          matlab.ui.control.Label
        Normalization_DataTable         matlab.ui.control.Table
        ExportFilesPanel                matlab.ui.container.Panel
        Normalization_SaveButton        matlab.ui.control.Button
        Normalization_ExportCSVNormalizedDataButton  matlab.ui.control.Button
        ViewOptionButtonGroup           matlab.ui.container.ButtonGroup
        Normalization_MultipleSpectraButton  matlab.ui.control.RadioButton
        Normalization_SelectDataTable   matlab.ui.control.Table
        Normalization_SamplePointSpinner  matlab.ui.control.Spinner
        SamplePointSpinnerLabel         matlab.ui.control.Label
        Normalization_SingleSpectrumButton  matlab.ui.control.RadioButton
        Normalization_AllSpectraButton  matlab.ui.control.RadioButton
        NormalizationMethodsButtonGroup  matlab.ui.container.ButtonGroup
        Normalization_MaxIntensityButton  matlab.ui.control.ToggleButton
        Normalization_EditRefMZ         matlab.ui.control.Button
        Normalization_ReferenceMZEditField  matlab.ui.control.NumericEditField
        ReferenceMZLabel                matlab.ui.control.Label
        Normalize_StartButton           matlab.ui.control.Button
        Normalization_NoiseLevelButton  matlab.ui.control.ToggleButton
        Normalization_MedianButton      matlab.ui.control.ToggleButton
        Normalization_pnormButton       matlab.ui.control.ToggleButton
        Normalization_pvalueSpinner     matlab.ui.control.Spinner
        pvalueSpinnerLabel              matlab.ui.control.Label
        Normalization_ReferencePeakButton  matlab.ui.control.ToggleButton
        Normalization_ioncountsButton   matlab.ui.control.ToggleButton
        Normalization_NormalizedMSPlot  matlab.ui.control.UIAxes
        Normalization_ProcessedMSPlot   matlab.ui.control.UIAxes
        PeakDetectionTab                matlab.ui.container.Tab
        ExportCMZButton                 matlab.ui.control.Button
        TotalnoofcommonpeaksEditField   matlab.ui.control.NumericEditField
        TotalnoofcommonpeaksEditFieldLabel  matlab.ui.control.Label
        TotalnoofpeaksEditField         matlab.ui.control.NumericEditField
        TotalnoofpeaksEditFieldLabel    matlab.ui.control.Label
        SampleAlignmentPanel            matlab.ui.container.Panel
        Detection_BANDEditField         matlab.ui.control.NumericEditField
        MaximumallowabledistanceLabel   matlab.ui.control.Label
        Detection_StartDetectionButton_3  matlab.ui.control.Button
        Binning_MethodDropDown_2        matlab.ui.control.DropDown
        MethodDropDownLabel_3           matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        Detection_SpectrumtodisplayEditField  matlab.ui.control.EditField
        SpectrumtodisplayoptionalLabel  matlab.ui.control.Label
        PeakDetectionLabel              matlab.ui.control.Label
        ExportPeakListButton            matlab.ui.control.Button
        BiningPanel                     matlab.ui.container.Panel
        CUTOFFSpinner                   matlab.ui.control.Spinner
        CUTOFFSpinnerLabel              matlab.ui.control.Label
        Detection_StartDetectionButton_2  matlab.ui.control.Button
        Binning_CriterionDropDown       matlab.ui.control.DropDown
        CriterionDropDownLabel          matlab.ui.control.Label
        Binning_MethodDropDown          matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        PeakDetectionPanel              matlab.ui.container.Panel
        Detection_MethodDropDown        matlab.ui.control.DropDown
        MethodDropDownLabel_2           matlab.ui.control.Label
        Detection_StartDetectionButton  matlab.ui.control.Button
        Detection_PeakThresholdEditField  matlab.ui.control.NumericEditField
        PeakThresholdEditFieldLabel     matlab.ui.control.Label
        Detection_MULTIPLIEREditField   matlab.ui.control.NumericEditField
        MULTIPLIEREditFieldLabel        matlab.ui.control.Label
        Detection_HEIGHTFILTEREditField  matlab.ui.control.NumericEditField
        HEIGHTFILTEREditFieldLabel      matlab.ui.control.Label
        Detection_BASEEditField         matlab.ui.control.NumericEditField
        BASEEditFieldLabel              matlab.ui.control.Label
        Binning_AlignedPeakBinningPlot  matlab.ui.control.UIAxes
        Binning_PeakBinningPlot         matlab.ui.control.UIAxes
        Detection_PeakDetectionPlot     matlab.ui.control.UIAxes
        PeakBinningTab                  matlab.ui.container.Tab
        Binning_StartBinningButton_2    matlab.ui.control.Button
        Binning_Table                   matlab.ui.control.Table
        PeakBinningLabel                matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CalculatedEdgesLabel            matlab.ui.control.Label
        CMZLabel                        matlab.ui.control.Label
        Binning_ViewOptionButtonGroup   matlab.ui.container.ButtonGroup
        Binning_MultipleSpectraButton   matlab.ui.control.RadioButton
        Binning_SelectDataTable         matlab.ui.control.Table
        Binning_SamplePointSpinner      matlab.ui.control.Spinner
        SamplePointSpinnerLabel_2       matlab.ui.control.Label
        Binning_SingleSpectrumButton    matlab.ui.control.RadioButton
        Binning_AllSpectraButton        matlab.ui.control.RadioButton
        PeakBinningPanel                matlab.ui.container.Panel
        Binning_ExportBinButton         matlab.ui.control.Button
        Binning_FileLabel               matlab.ui.control.Label
        Binning_ImportEdgeButton        matlab.ui.control.Button
        Binning_ImportEdgesCheckBox     matlab.ui.control.CheckBox
        Binning_ExportButton            matlab.ui.control.Button
        Binning_ToleranceEditField      matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        Binning_NumberofBinsSpinner     matlab.ui.control.Spinner
        MaximumnoofpeaksineachbinLabel  matlab.ui.control.Label
        Binning_BinningMethod           matlab.ui.control.DropDown
        MethodDropDownLabel_4           matlab.ui.control.Label
        Binning_StartBinningButton      matlab.ui.control.Button
        Binning_EdgeListPlot            matlab.ui.control.UIAxes
        PeakBinningFinalPlot            matlab.ui.control.UIAxes
        DataSplittingTab                matlab.ui.container.Tab
        TestSetPanel                    matlab.ui.container.Panel
        Normalization_DataTable_3       matlab.ui.control.Table
        TrainSetPanel                   matlab.ui.container.Panel
        Normalization_DataTable_2       matlab.ui.control.Table
        MLPreprocessingLabel            matlab.ui.control.Label
        DatasplittingPanel              matlab.ui.container.Panel
        ExportSplitDataButton           matlab.ui.control.Button
        TestSetNumberofSpectraEditField  matlab.ui.control.NumericEditField
        TestSetNumberofSpectraEditFieldLabel  matlab.ui.control.Label
        TrainSetNumberofSpectraEditField  matlab.ui.control.NumericEditField
        TrainSetNumberofSpectraEditFieldLabel  matlab.ui.control.Label
        SplitButton                     matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        TestSetEditField                matlab.ui.control.NumericEditField
        TestSetEditFieldLabel           matlab.ui.control.Label
        TrainSetEditField               matlab.ui.control.NumericEditField
        TrainSetEditFieldLabel          matlab.ui.control.Label
        NextButton_2                    matlab.ui.control.Button
        DataInformationPanel            matlab.ui.container.Panel
        DetailProtectagainstoverfittingbypartitioningthedatasetLabel_2  matlab.ui.control.Label
        ExportPreprocessedDataforMLButton  matlab.ui.control.Button
        DoneLabel                       matlab.ui.control.Label
        StartButton_3                   matlab.ui.control.Button
        FeatureNumberEditField          matlab.ui.control.EditField
        FeatureNumberLabel              matlab.ui.control.Label
        ClassNameListBox                matlab.ui.control.ListBox
        ClassNameLabel                  matlab.ui.control.Label
        TotalSpectraEditField           matlab.ui.control.EditField
        TotalSpectraLabel               matlab.ui.control.Label
        ClassNumberEditField            matlab.ui.control.EditField
        ClassNumberLabel                matlab.ui.control.Label
        ProjectNameLabel                matlab.ui.control.Label
        ExportDataForModelAllButton     matlab.ui.control.Button
        ModelTrainingTab                matlab.ui.container.Tab
        ModelInformationPanel           matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        NextButton_3                    matlab.ui.control.Button
        ClassificationLearnerButton     matlab.ui.control.Button
        TrainedModelInformationPanel    matlab.ui.container.Panel
        HeldoutEditField                matlab.ui.control.NumericEditField
        HeldoutEditFieldLabel           matlab.ui.control.Label
        FoldsEditField                  matlab.ui.control.NumericEditField
        FoldsEditFieldLabel             matlab.ui.control.Label
        ClassLossEditField_2            matlab.ui.control.NumericEditField
        ClassLossEditField_2Label       matlab.ui.control.Label
        ValidationAccuracyPercentEditField  matlab.ui.control.NumericEditField
        ValidationAccuracyPercentLabel  matlab.ui.control.Label
        ExportModelButton               matlab.ui.control.Button
        ValidationTypeEditField         matlab.ui.control.EditField
        ValidationTypeEditFieldLabel    matlab.ui.control.Label
        ModelTypeEditField              matlab.ui.control.EditField
        ModelTypeEditFieldLabel         matlab.ui.control.Label
        ModelNameEditField              matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        OptimizationOptionPanel         matlab.ui.container.Panel
        IterationsSpinner               matlab.ui.control.Spinner
        IterationsSpinnerLabel          matlab.ui.control.Label
        AcquisitionfunctionDropDown     matlab.ui.control.DropDown
        AcquisitionfunctionLabel        matlab.ui.control.Label
        OptimizerDropDown               matlab.ui.control.DropDown
        OptimizerLabel                  matlab.ui.control.Label
        TrainingDataDetailPanel         matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        ClassListListBox                matlab.ui.control.ListBox
        ClassListListBoxLabel           matlab.ui.control.Label
        ClassNumberEditField_2          matlab.ui.control.NumericEditField
        ClassNumberEditField_2Label     matlab.ui.control.Label
        FeatureNumberEditField_2        matlab.ui.control.NumericEditField
        FeatureNumberEditField_2Label   matlab.ui.control.Label
        DataNumberEditField             matlab.ui.control.NumericEditField
        DataNumberEditFieldLabel        matlab.ui.control.Label
        ModelTrainingLabel              matlab.ui.control.Label
        ModelSelectionButtonGroup       matlab.ui.container.ButtonGroup
        ModelTypeLabel                  matlab.ui.control.Label
        ModelNameLabel                  matlab.ui.control.Label
        DropDown_6                      matlab.ui.control.DropDown
        DropDown_5                      matlab.ui.control.DropDown
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        NeuralNetworkButton             matlab.ui.control.ToggleButton
        NearestNeighborButton           matlab.ui.control.ToggleButton
        SupportVectorMachinesButton     matlab.ui.control.ToggleButton
        NaiveBayesButton                matlab.ui.control.ToggleButton
        DiscriminantAnalysisButton      matlab.ui.control.ToggleButton
        DecisionTreesButton             matlab.ui.control.ToggleButton
        ValidationButtonGroup           matlab.ui.container.ButtonGroup
        DetailNoprotectionagainstoverfittingTheappusesallthedataLabel  matlab.ui.control.Label
        RecommendedforlargedatasetsLabel  matlab.ui.control.Label
        DetailProtectagainstoverfittingbypartitioningthedatasetLabel  matlab.ui.control.Label
        PercentheldoutSpinner           matlab.ui.control.Spinner
        PercentheldoutLabel             matlab.ui.control.Label
        CrossValidationfoldsSpinner     matlab.ui.control.Spinner
        CrossValidationfoldsSpinnerLabel  matlab.ui.control.Label
        ResubstitutionValidationButton  matlab.ui.control.RadioButton
        HoldoutValidationButton         matlab.ui.control.RadioButton
        CrossValidationButton           matlab.ui.control.RadioButton
        TrainModelButton                matlab.ui.control.Button
        ModelResultsTab                 matlab.ui.container.Tab
        AUCEditField                    matlab.ui.control.NumericEditField
        AUCEditFieldLabel               matlab.ui.control.Label
        PositiveClassDropDown           matlab.ui.control.DropDown
        PositiveClassLabel              matlab.ui.control.Label
        ROCcurveLabel                   matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        TestDataSet                     matlab.ui.control.Label
        TestAccuracyPercentEditField    matlab.ui.control.NumericEditField
        TestAccuracyPercentEditFieldLabel  matlab.ui.control.Label
        Preprocessing_ExportButton_3    matlab.ui.control.Button
        Preprocessing_ExportButton_2    matlab.ui.control.Button
        ResultLabel                     matlab.ui.control.Label
        ResultTable                     matlab.ui.control.Table
        ClassificationResultsLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        PredictionResultPlot            matlab.ui.control.UIAxes
        ProjectInfoTab                  matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        Label_4                         matlab.ui.control.Label
        AboutMSpecLabel                 matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        AboutYourProjectPanel           matlab.ui.container.Panel
        ExportPreProcessedSettingsButton  matlab.ui.control.Button
        DataExportPanel                 matlab.ui.container.Panel
        Export_PreprocessingCheckBox_3  matlab.ui.control.CheckBox
        Export_MLPreprocessingCheckBox_2  matlab.ui.control.CheckBox
        StepsLabel_2                    matlab.ui.control.Label
        Export_PeakBinningCheckBox      matlab.ui.control.CheckBox
        ExportCSVFilesButton            matlab.ui.control.Button
        Export_PreprocessingCheckBox    matlab.ui.control.CheckBox
        Export_NormalizationCheckBox    matlab.ui.control.CheckBox
        Export_PeakDetectionCheckBox    matlab.ui.control.CheckBox
        ProjectInfo_CreatedDate         matlab.ui.control.EditField
        CreatedLabel                    matlab.ui.control.Label
        ReportGenerationPanel           matlab.ui.container.Panel
        StepsLabel                      matlab.ui.control.Label
        PeakBinningCheckBox             matlab.ui.control.CheckBox
        GeneratePDFReportButton         matlab.ui.control.Button
        PreprocessingCheckBox           matlab.ui.control.CheckBox
        NormalizationCheckBox           matlab.ui.control.CheckBox
        PeakDetectionCheckBox           matlab.ui.control.CheckBox
        EditButton                      matlab.ui.control.StateButton
        ProjectInfo_DescriptionTextArea  matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        ProjectInfo_ImportedFileEditField  matlab.ui.control.EditField
        ImportedFileEditFieldLabel      matlab.ui.control.Label
        ProjectInfo_ProjectNameEditField  matlab.ui.control.EditField
        ProjectNameEditField_2Label     matlab.ui.control.Label
    end


    properties (Access = public)

        CurrentProject MSProject; %current project
        MZPickerApp % app to select ref MZ
        
        ProjectName
        ProjectDescription
        Class = ""
        ClassNum
        Status = ""
        importedData
        CreateProject = "No"
        
        
        %importedData1 MLData
        %importedData2 MLData
        %importedData3 MLData
        %importedData4 MLData
        %importedData5 MLData
        %importedData6 MLData
        %importedData7 MLData
        %importedData8 MLData
        %importedData9 MLData
        %importedData10 MLData
        importedMLData MLData
        ModelTest MSAnalysisProject
        
        
        %This properties are workable in custom class number
        PrePreocessedML %array
        TableMLExport %Table
        CellMLExport %cell
        
        %Split Data
        TrainSet %table
        TestSet %table
        TrainSetPer
        TestSetPer
        
        %MdlImportApp ModelImportApp
        
        %Model Training
        folds
        percent
        TrainedModel
        ValidatedModel
        X
        Y
        ValAcc
        classLoss
        mtype
        
        %Optimization
        aqfn
        optimizer
        iteration
        
        %TestModel
        ClassNames
        PredictionResult
        ScoreMatrix

    end

    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MSpecController.getRecentFiles(app);
        end

        % Callback function
        function NewProjectMenuSelected(app, event)
            %if isNull(app.CurrentProject)
            %app.TabGroup.SelectedTab = app.ImportTab;
            %end
            %[file, path] = uigetfile('*.csv');
            %if isequal(file,0)
            %msgbox('Please input CSV files')
            %else
            %app.FileName = file;
            %app.DataArray = csvread(fullfile(path,file));
            % Data Array Preprocess and Properties
            % column 1 is used for plotting the x-axis
            %app.XAxisPlot = app.DataArray(:, 1);
            % removing the first column from the Array
            %app.DataArray(:,1) = [];
            % removing last col ( all 0s )
            % app.DataArray(:,81) = [];

            %plotting the raw data
            %[app.RowNumber,app.ColumnNumber]= size(app.DataArray);
            %hold(app.Normalization_ProcessedMSPlot,"on");
            %for i = 1:app.ColumnNumber
            %stem(app.Normalization_ProcessedMSPlot,app.XAxisPlot,app.DataArray(:, i));
            %end
            %hold(app.Normalization_ProcessedMSPlot,"off");

            %updateNormalizedArray(app);
            %setDefaultReferencePeak(app);
            %DisplaySamplePointOption(app);
            %DEMO_DisplayClassOption(app);
            %end
        end

        % Selection changed function: 
        % NormalizationMethodsButtonGroup
        function NormalizationMethodsButtonGroupSelectionChanged(app, event)
            selectedButton = app.NormalizationMethodsButtonGroup.SelectedObject;
            app.Normalization_pvalueSpinner.Enable = false;
            switch selectedButton % Get Tag of selected object.
                case app.Normalization_ioncountsButton
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Sum';
                case app.Normalization_pnormButton
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Norm';
                    app.Normalization_pvalueSpinner.Enable = true;
                case app.Normalization_MedianButton
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Median';
                case app.Normalization_NoiseLevelButton
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Noise';
                case app.Normalization_MaxIntensityButton
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Max';
                otherwise
                    app.CurrentProject.PreprocessedData.NormalizeMethod='Peak';
                    % init MZ Picker App
                    app.MZPickerApp = MZPicker(app);
            end
        end

        % Callback function
        function FeaturePeakSliderValueChanged(app, event)
            value = app.FeaturePeakSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MinorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MinorTicks(minIdx);
            app.FeaturePeak = value;
            app.SelectedPeakLabel.Text = ['Selected Peak:',num2str(app.FeaturePeak)];
            plotNormalizedData(app);
        end

        % Callback function
        function Normalization_PeakSpinnerValueChanged(app, event)
            app.CurrentProject.PreprocessedData.ReferencePeak = app.Normalization_PeakSpinner.Value;
            %MSpecController.updateNormalizedSpectra(app);
        end

        % Button pushed function: 
        % Normalization_ExportCSVNormalizedDataButton
        function Normalization_ExportCSVNormalizedDataButtonPushed(app, event)
            DataImportExport.exportNormalizedData(app);
        end

        % Value changed function: Normalization_pvalueSpinner
        function Normalization_pvalueSpinnerValueChanged(app, event)
            app.CurrentProject.PreprocessedData.NormalizationNormValue = app.Normalization_pvalueSpinner.Value;
        end

        % Callback function
        function DegreeSpinnerValueChanged(app, event)
            app.RegressionDegree = app.DegreeSpinner.Value;
        end

        % Selection changed function: ViewOptionButtonGroup
        function ViewOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ViewOptionButtonGroup.SelectedObject;
            app.Normalization_SamplePointSpinner.Enable = false;
            app.Normalization_SelectDataTable.Enable = 'off';
            switch selectedButton % Get Tag of selected object.
                case app.Normalization_AllSpectraButton
                    app.CurrentProject.PreprocessedData.NormalizeDisplay='All';
                case app.Normalization_SingleSpectrumButton
                    app.CurrentProject.PreprocessedData.NormalizeDisplay='Single';
                    app.Normalization_SamplePointSpinner.Enable = true;
                otherwise
                    app.CurrentProject.PreprocessedData.NormalizeDisplay='Multiple';
                    app.Normalization_SelectDataTable.Enable = 'on';
            end
            Visualization.plotNormalizedSpectra(app);
        end

        % Value changed function: Normalization_SamplePointSpinner
        function Normalization_SamplePointSpinnerValueChanged(app, event)
            Visualization.plotNormalizedSpectra(app);
        end

        % Cell edit callback: Normalization_SelectDataTable
        function Normalization_SelectDataTableCellEdit(app, event)
            Visualization.plotNormalizedSpectra(app);
        end

        % Callback function
        function ColorMapDropDownValueChanged(app, event)
            colormap(app.Imaging_Display,app.ColorMapDropDown.Value);
        end

        % Callback function
        function SelectClassTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            DEMO_Analysis_Image(app);
        end

        % Button pushed function: Import_ImportButton
        function ImportButton_Pushed(app, event)
            DataImportExport.importData(app);
        end

        % Button pushed function: Import_CreateProjectButton
        function Import_CreateProjectButtonPushed(app, event)
            DataImportExport.createProject(app);
            app.SaveMenu.Enable = 'on';
        end

        % Button pushed function: Preprocessing_PlotButton
        function Preprocessing_PlotButtonPushed(app, event)
            MSpecController.plotButtonPushedHandler(app);
        end

        % Button pushed function: Preprocessing_SaveButton
        function Preprocessing_SaveButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.NormalizationTab;
        end

        % Button pushed function: Detection_StartDetectionButton
        function Detection_StartDetectionButtonPushed(app, event)
            MSpecController.startPeakDetection(app);
        end

        % Value changed function: Detection_MethodDropDown
        function Detection_MethodDropDownValueChanged(app, event)
            value = app.Detection_MethodDropDown.Value;
            switch value % Get Tag of selected object.
                case 'Auto'
                    app.Detection_BASEEditField.Enable = false;
                    app.Detection_MULTIPLIEREditField.Enable = false;
                    app.Detection_HEIGHTFILTEREditField.Enable = false;
                    app.Detection_PeakThresholdEditField.Enable = false;
                otherwise
                    app.Detection_BASEEditField.Enable = true;
                    app.Detection_MULTIPLIEREditField.Enable = true;
                    app.Detection_HEIGHTFILTEREditField.Enable = true;
                    app.Detection_PeakThresholdEditField.Enable = true;
            end
        end

        % Button pushed function: Detection_StartDetectionButton_2
        function Detection_StartDetectionButton_2Pushed(app, event)
            MSpecController.startPeakBinning(app);
        end

        % Button pushed function: Detection_StartDetectionButton_3
        function Detection_StartDetectionButton_3Pushed(app, event)
            MSpecController.startAlignPeakBinning(app)
        end

        % Button pushed function: Binning_StartBinningButton
        function Binning_StartBinningButtonPushed(app, event)
            MSpecController.startRealPeakBinning(app);
        end

        % Button pushed function: Preprocessing_ExportButton
        function Preprocessing_ExportButtonPushed(app, event)
            DataImportExport.exportPreprocessedData(app);
        end

        % Selection changed function: Binning_ViewOptionButtonGroup
        function Binning_ViewOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.Binning_ViewOptionButtonGroup.SelectedObject;
            app.Binning_SamplePointSpinner.Enable = false;
            app.Binning_SelectDataTable.Enable = 'off';
            switch selectedButton % Get Tag of selected object.
                case app.Binning_AllSpectraButton
                    app.CurrentProject.PreprocessedData.BinningDisplay='All';
                case app.Binning_SingleSpectrumButton
                    app.CurrentProject.PreprocessedData.BinningDisplay='Single';
                    app.Binning_SamplePointSpinner.Enable = true;
                otherwise
                    app.CurrentProject.PreprocessedData.BinningDisplay='Multiple';
                    app.Binning_SelectDataTable.Enable = 'on';
            end
            try
            MSpecController.Binning_updateBinningPlot(app);
            catch
            end
        end

        % Value changed function: Binning_SamplePointSpinner
        function Binning_SamplePointSpinnerValueChanged(app, event)
            MSpecController.Binning_updateBinningPlot(app);
        end

        % Cell edit callback: Binning_SelectDataTable
        function Binning_SelectDataTableCellEdit(app, event)
            MSpecController.Binning_updateBinningPlot(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            MSpecController.loadRecentFiles(app);
        end

        % Button pushed function: Binning_ExportButton
        function Binning_ExportButtonPushed(app, event)
            DataImportExport.exportBinnedData(app);
        end

        % Value changed function: Binning_ImportEdgesCheckBox
        function Binning_ImportEdgesCheckBoxValueChanged(app, event)
            value = app.Binning_ImportEdgesCheckBox.Value;
            switch value
                case true
                    app.Binning_ImportEdgeButton.Enable = true;
                    app.Binning_BinningMethod.Enable = false;
                    app.Binning_NumberofBinsSpinner.Enable = false;
                    app.Binning_ToleranceEditField.Enable = false;
                otherwise
                    app.Binning_ImportEdgeButton.Enable = false;
                    app.Binning_BinningMethod.Enable = true;
                    app.Binning_NumberofBinsSpinner.Enable = true;
                    app.Binning_ToleranceEditField.Enable = true;
            end
        end

        % Button pushed function: Binning_ExportBinButton
        function Binning_ExportBinButtonPushed(app, event)
            DataImportExport.exportBinEdges(app);
        end

        % Button pushed function: Binning_ImportEdgeButton
        function Binning_ImportEdgeButtonPushed(app, event)
            DataImportExport.importBinEdges(app);
        end

        % Value changed function: EditButton
        function EditButtonValueChanged(app, event)
            value = app.EditButton.Value;
            if value==1
                % Enable Edit Field
                app.EditButton.Text = 'Save';
                app.ProjectInfo_DescriptionTextArea.Editable = true;
            else
                % Save
                app.CurrentProject.Description = app.ProjectInfo_DescriptionTextArea.Value;
                app.EditButton.Text = 'Edit';
                app.ProjectInfo_DescriptionTextArea.Editable = false;
            end
        end

        % Button pushed function: Normalize_StartButton
        function Normalize_StartButtonPushed(app, event)
            MSpecController.updateNormalizedSpectra(app);
        end

        % Button pushed function: Normalization_EditRefMZ
        function Normalization_EditRefMZPushed(app, event)
            app.MZPickerApp = MZPicker(app);
        end

        % Value changed function: Normalization_ReferenceMZEditField
        function Normalization_ReferenceMZEditFieldValueChanged(app, event)
            app.CurrentProject.PreprocessedData.ReferencePeak = app.Normalization_ReferenceMZEditField.Value;
        end

        % Button pushed function: ExportCSVFilesButton
        function ExportCSVFilesButtonPushed(app, event)
            DataImportExport.exportAllData(app);
        end

        % Button pushed function: ExportPeakListButton
        function ExportPeakListButtonPushed(app, event)
            try
            DataImportExport.exportDetectedPeak(app);
            catch
            end
        end

        % Button pushed function: GeneratePDFReportButton
        function GeneratePDFReportButtonPushed(app, event)
            ReportGeneration.generatePDFReport(app);
        end

        % Callback function: PushTool8, SaveAsMenu
        function PushTool8Clicked(app, event)
           MSpecController.saveAs(app);
        end

        % Callback function
        function DataTypesButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataTypesButtonGroup.SelectedObject;
            switch selectedButton
                case app.Imaging2DButton
                    app.HeightField.Editable = 'on';
                    app.WidthField.Editable = 'on';
                otherwise
                    app.HeightField.Editable = 'off';
                    app.WidthField.Editable = 'off';
            end
        end

        % Button pushed function: ExportPreProcessedSettingsButton
        function ExportPreProcessedSettingsButtonPushed(app, event)
            DataImportExport.exportPreprocessingSettings(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PeakBinningTab;
        end

        % Button pushed function: Normalization_SaveButton
        function Normalization_SaveButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.PeakDetectionTab;
        end

        % Callback function: ExitMenu, MSPECAppUIFigure
        function MSPECAppUIFigureCloseRequest(app, event)
            if ~isempty(app.CurrentProject)
                MSpecController.requestExit(app);
            else
                delete(app);
            end
        end

        % Callback function: NewProjectMenu, PushTool
        function PushToolClicked(app, event)
            app.TabGroup.SelectedTab = app.ImportTab;
            DataImportExport.importData(app);
        end

        % Clicked callback: PushTool4
        function PushTool4Clicked(app, event)
            app.TabGroup.SelectedTab = app.ProjectInfoTab;
            DataImportExport.exportAllData(app);
        end

        % Clicked callback: PushTool7
        function PushTool7Clicked(app, event)
            app.TabGroup.SelectedTab = app.ProjectInfoTab;
            ReportGeneration.generatePDFReport(app);
        end

        % Button pushed function: ExportCMZButton
        function ExportCMZButtonPushed(app, event)
             DataImportExport.exportCMZ(app);
        end

        % Menu selected function: ContactUsMenu
        function ContactUsMenuSelected(app, event)
            msgbox({'Thank you for all the love and support for MSpec! ♥';'';'Please feel free to email with any comments, questions, suggestions or concerns';'Email: piradaboonna@gmail.com';'Email: mink.ost26@gmail.com'},'Contact Us');
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            url = 'https://sites.google.com/view/mspec/';
            web(url)
        end

        % Callback function: PushTool9, SaveMenu
        function PushTool9Clicked(app, event)
            MSpecController.saveChanges(app);
        end

        % Clicked callback: PushTool6
        function PushTool6Clicked(app, event)
            url = 'https://sites.google.com/view/mspec/';
            web(url)
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            app.TabGroup.SelectedTab = app.ImportTab;
        end

        % Button pushed function: ExportDataForModelAllButton
        function ExportForModelButtonPushed(app, event)
            %DataImportExportML.exportDataforModel(app);
            DataImportExportML.ExportAllMLData(app);
        end

        % Selection changed function: PurposeButtonGroup
        function PurposeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PurposeButtonGroup.SelectedObject;
                DataImportExport.PickDataPurpose(app,selectedButton); 
        end

        % Value changing function: ProjectNameEditField_2
        function ProjectNameEditField_2ValueChanging(app, event)
            changingValue = event.Value;
            %value = app.ProjectNameEditField_2.Value;
            DataImportExportML.ProjectTitle(app,changingValue);
        end

        % Button pushed function: Next_CreateProjectButton_2
        function Next_CreateProjectButton_2Pushed(app, event)
            app.TabGroup2.SelectedTab = app.ImportDataTab;
            app.Panel_2.Enable = 'on';  
            DataImportExportML.NextButtonHandler(app);
        end

        % Callback function
        function DataTypesButtonGroup_2SelectionChanged(app, event)
            %selectedButton = app.DataTypesButtonGroup_2.SelectedObject;
            
        end

        % Button pushed function: Import_ImportButton_3
        function Import_ImportButton_3Pushed(app, event)
            DataImportExportML.importData(app);
        end

        % Callback function
        function showbt(app, event)
            DataImportExportML.showdata();
        end

        % Value changed function: ClassDropDown_2
        function ClassDropDown_2ValueChanged(app, event)
            value = app.ClassDropDown_2.Value;
            
             for i = 1:app.ClassNum
                if value == "Data Class "+int2str(i) && app.Status(i) == "imported"
                    show = app.importedData;
                    c = app.Class(i);
                    
                   
                    %[~, fName, ~] = fileparts(app.importedData.FileName(i));
                    app.ImportStatusLabel_3.Text = [app.importedData.FileName(i)];
                    app.ClassNameEditField_2.Value = c;
                    app.NumberofMassSpectraEditField_3.Value = app.importedData.NumberOfSpectra(i);
                    app.WidthField_3.Value = 1;
                    app.HeightField_3.Value = app.importedData.NumberOfSpectra(i);
     
                end
                
             end
             
             for i = 1:app.ClassNum
                 if value == "Data Class "+int2str(i) && app.Status(i) ~= "imported"
                   
                    app.ImportStatusLabel_3.Text = 'Please import your file.';
                    try
                    app.ClassNameEditField_2.Value = c;
                    catch
                    app.ClassNameEditField_2.Value = "";
                    end
                    app.NumberofMassSpectraEditField_3.Value = 0;
                    app.WidthField_3.Value = 0;
                    app.HeightField_3.Value = 0;    
          
                 end
             end
             
            %{
            switch value
                    case "Data Class 1"
                       show = app.importedData1;
                       c = app.Class(1);
                    case "Data Class 2"
                       show = app.importedData2;
                       c = app.Class(2);
                    case "Data Class 3"
                        show = app.importedData3;
                        c = app.Class(3);
                    case "Data Class 4"
                       show = app.importedData4;
                       c = app.Class(4);
                    case "Data Class 5"
                        show = app.importedData5;
                        c = app.Class(5);
                    case "Data Class 6"
                         show =  app.importedData6;
                         c = app.Class(6);
                    case "Data Class 7"
                        show =  app.importedData7;
                        c = app.Class(7);
                    case "Data Class 8"
                       show =  app.importedData8;
                       c = app.Class(8);
                    case "Data Class 9"
                        show =  app.importedData9;
                        c = app.Class(9);
                    case "Data Class 10"
                       show = app.importedData10;
                       c = app.Class(10);
            end

            %}
            
            
        end

        % Value changing function: ClassNameEditField_2
        function ClassNameEditField_2ValueChanging(app, event)
            changingValue = event.Value;
             if changingValue ~= "" || app.ClassNameEditField_2.Value == ""
               app.PleasefilltheclassnameLabel.Visible = "off";  
             end
            Pickedvalue = app.ClassDropDown_2.Value;
            
            for i = 1:app.ClassNum
                if Pickedvalue == "Data Class "+int2str(i)
                    app.Class(i) = changingValue;
                end
            end
           %{ 
            if app.CreateProject == "Yes"
            if app.Class(1:app.ClassNum) ~= ""
                app.Import_CreateProjectButton_4.Enable = 'on';
            end
            
            if app.Class(1:app.ClassNum) == ""
                app.Import_CreateProjectButton_4.Enable = 'off';
            end
            end
           %}
            
            %{
            switch Pickedvalue
                    case "Data Class 1"
                        app.Class(1) = changingValue;
                    case "Data Class 2"
                         app.Class(2) = changingValue;
                    case "Data Class 3"
                       app.Class(3) = changingValue;
                    case "Data Class 4"
                       app.Class(4) = changingValue;
                    case "Data Class 5"
                        app.Class(5) = changingValue;
                    case "Data Class 6"
                        app.Class(6) = changingValue;
                    case "Data Class 7"
                        app.Class(7) = changingValue;
                    case "Data Class 8"
                         app.Class(8) = changingValue;
                    case "Data Class 9"
                      app.Class(9) = changingValue;
                    case "Data Class 10"
                       app.Class(10) = changingValue;

            %}
           
        end

        % Button pushed function: Import_CreateProjectButton_4
        function Import_CreateProjectButton_4Pushed(app, event)
            %try
            DataImportExportML.MixData(app);
            app.SaveMenu.Enable = 'on';
            app.TabGroup.SelectedTab = app.PreprocessingTab;
            app.TrainModelButton.Enable = 'on';
            app.ClassificationLearnerButton.Enable = 'on';
            app.StartButton_3.Enable = 'on';
            app.ProjectInfo_ProjectNameEditField.Value = app.ProjectName;
            app.ProjectInfo_DescriptionTextArea.Value = app.ProjectDescription;
            app.PositiveClassDropDown.Items = app.Class;


            %catch
             %app.PleasefilltheclassnameLabel.Visible = "on";   
            %end
        end

        % Callback function
        function ImportModelButtonPushed(app, event)
             d = uiprogressdlg(app.MSPECAppUIFigure,'Title','Please Wait',...
                    'Message','Opening the import window');
            d.Message = 'Loading...';
            d.Value = .33; 
            pause(1);
            d.Value = .55; 
            d.Message = 'Loading...';
            pause(1);
            d.Value = .88;
            d.Message = 'Openning ModelImport App...';
            pause(0.3);
            app.MdlImportApp = ModelImportApp(app);
        end

        % Callback function
        function TrainModelButtonPushed(app, event)
            %app.ClassiLearner = ClassificationLearner(app);
            d = uiprogressdlg(app.MSPECAppUIFigure,'Title','Please Wait',...
                    'Message','Opening the import window');
            d.Message = 'Loading...';
            d.Value = .33; 
            pause(1);
            d.Value = .55; 
            d.Message = 'Loading...';
            pause(1);
            d.Value = .88;
            d.Message = 'Openning ClassificationLearner App...';
            pause(0.3);
            if ~isempty(app.TrainSet)
                %open classification leaner with Train in it
                 Y = table2array(app.TrainSet(1:end,end));
                 Tb1 = app.TrainSet(1:end,1:end-1);
                 [~,y] = size(Tb1);
                 for i = 1:y
                 Tb1.Properties.VariableNames(i) = "Feature"+int2str(i);
                 end
                 classificationLearner(Tb1,Y);
            elseif isempty(app.TrainSet)
                classificationLearner
            end
            
        end

        % Button pushed function: Binning_StartBinningButton_2
        function Binning_StartBinningButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.DataSplittingTab;
            
            try
            Preprocessing.PeakBinningNextButton(app);
            catch
            end
            
        end

        % Button pushed function: StartButton_3
        function StartButton_3Pushed(app, event)
            Preprocessing.MLPrePos(app);
            app.Export_MLPreprocessingCheckBox_2.Enable = "on";
            app.ExportPreprocessedDataforMLButton.Enable = "on";
            app.SplitButton.Enable = "on";
        end

        % Button pushed function: ExportPreprocessedDataforMLButton
        function ExportPreprocessedDataforMLButtonPushed(app, event)
            try
            DataImportExportML.exportDataforModel(app);
            catch
                disp("Please press the start button");
            end
        end

        % Callback function
        function NextButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.DataPredictionSettingTab;
        end

        % Callback function
        function Preprocessing_ExportButton_8Pushed(app, event)
            app.TabGroup.SelectedTab = app.ModelResultsTab;
        end

        % Button pushed function: SplitButton
        function SplitButtonPushed(app, event)
           Preprocessing.SplitData(app);
           app.ExportSplitDataButton.Enable = "on";
           app.ExportDataForModelAllButton.Enable = "on";
           app.Export_PreprocessingCheckBox_3.Enable = "on";
           app.UITable2.Data = app.TestSet;
        end

        % Button pushed function: ExportSplitDataButton
        function ExportSplitDataButtonPushed(app, event)
            DataImportExportML.ExportSplitData(app);
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            
            
            app.TabGroup.SelectedTab = app.ModelTrainingTab;
            try
                TrainModel.initInfo(app);
            catch 
            end
            
        end

        % Value changed function: ProjectNameEditField_2
        function ProjectNameEditField_2ValueChanged(app, event)
            value = app.ProjectNameEditField_2.Value;
            
        end

        % Value changing function: ClassNumberSpinner
        function ClassNumberSpinnerValueChanging(app, event)
         
        end

        % Button down function: DataTypesButtonGroup_4
        function DataTypesButtonGroup_4ButtonDown(app, event)
            
        end

        % Selection changed function: DataTypesButtonGroup_4
        function DataTypesButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.DataTypesButtonGroup_4.SelectedObject;
            switch selectedButton
                case app.MassSpectraButton_3
                    %disp("MassSpec");
                    app.Preprocessing_RawMSPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Preprocessing_RawMSPlot.Title.String = "Raw Mass Spectra";
                    app.Preprocessing_PreprocessedMSPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Preprocessing_PreprocessedMSPlot.Title.String = "Pre-Processed Mass Spectra";
                    app.Normalization_ProcessedMSPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Normalization_NormalizedMSPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Detection_PeakDetectionPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Binning_PeakBinningPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Binning_PeakBinningPlot.Title.String = "Common Mass/Charge (M/Z) Locations";
                    app.Binning_AlignedPeakBinningPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Binning_AlignedPeakBinningPlot.Title.String = "Peaks Aligned to the Common Mass/Charge (M/Z)";
                    app.PeakBinningFinalPlot.XLabel.String = "Mass/Charge Bins";
                case app.RamanSpectraButton_4
                    %disp("Raman");
                    app.Preprocessing_RawMSPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Preprocessing_RawMSPlot.Title.String = "Raw Raman Spectra";
                    app.Preprocessing_PreprocessedMSPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Preprocessing_PreprocessedMSPlot.Title.String = "Pre-Processed Raman Spectra";
                    app.Normalization_ProcessedMSPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Normalization_NormalizedMSPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Detection_PeakDetectionPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Binning_PeakBinningPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Binning_PeakBinningPlot.Title.String = "Common Raman Shift (cm^-1) Locations";
                    app.Binning_AlignedPeakBinningPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Binning_AlignedPeakBinningPlot.Title.String = "Peaks Aligned to the Common Raman Shift(cm^-1)";
                    app.PeakBinningFinalPlot.XLabel.String = "Raman Shift Bins";
            end
        end

        % Callback function
        function ImportDataforPredictionButtonPushed(app, event)
            %try
            MSpecModeling.importData(app);
            MSpecModeling.createProject (app);
            %catch
               %app.ImportStatusLabel_4.Text = "Invalid file, please try again. . .";
            %end
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed2(app, event)
            %try
            TrainModel.trainModel(app);
            app.Preprocessing_ExportButton_2.Enable = 'on';
            app.ExportModelButton.Enable = 'on';
            %catch
            %end
        end

        % Selection changed function: ValidationButtonGroup
        function ValidationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ValidationButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.CrossValidationButton
                    app.CrossValidationfoldsSpinner.Enable = 'on';
                    app.PercentheldoutSpinner.Enable = 'off';
                case app.HoldoutValidationButton
                     app.CrossValidationfoldsSpinner.Enable = 'off';
                     app.PercentheldoutSpinner.Enable = 'on';
                case app.ResubstitutionValidationButton
                    app.CrossValidationfoldsSpinner.Enable = 'off';
                    app.PercentheldoutSpinner.Enable = 'off';
            end
            %{
            if selectedButton == app.CrossValidationButton
                app.CrossValidationfoldsSpinner.Enable = 'on';
                app.PercentheldoutSpinner.Enable = 'off';


            elseif selectedButton == app.HoldoutValidationButton
                app.CrossValidationfoldsSpinner.Enable = 'off';
                app.PercentheldoutSpinner.Enable = 'on';
            
            
            elseif selectedButton == app.HoldoutValidationButton
                app.CrossValidationfoldsSpinner.Enable = 'off';
                app.PercentheldoutSpinner.Enable = 'off';
            end
            %}
            
        end

        % Button down function: ValidationButtonGroup
        function ValidationButtonGroupButtonDown(app, event)
         
        end

        % Selection changed function: ModelSelectionButtonGroup
        function ModelSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelSelectionButtonGroup.SelectedObject;
            app.OptimizationOptionPanel.Enable = 'off';
            switch selectedButton
                case app.DecisionTreesButton
                    app.DropDown.Enable = 'off';
                    app.DropDown_2.Enable = 'on';
                    app.DropDown_3.Enable = 'off';
                    app.DropDown_4.Enable = 'off';
                    app.DropDown_5.Enable = 'off';
                    app.DropDown_6.Enable = 'off';
                case app.DiscriminantAnalysisButton
                    app.DropDown.Enable = 'on';
                    app.DropDown_2.Enable = 'off';
                    app.DropDown_3.Enable = 'off';
                    app.DropDown_4.Enable = 'off';
                    app.DropDown_5.Enable = 'off';
                    app.DropDown_6.Enable = 'off';
                case app.NaiveBayesButton
                    app.DropDown.Enable = 'off';
                    app.DropDown_2.Enable = 'off';
                    app.DropDown_3.Enable = 'off';
                    app.DropDown_4.Enable = 'off';
                    app.DropDown_5.Enable = 'on';
                    app.DropDown_6.Enable = 'off';
                case app.SupportVectorMachinesButton
                    app.DropDown.Enable = 'off';
                    app.DropDown_2.Enable = 'off';
                    app.DropDown_3.Enable = 'off';
                    app.DropDown_4.Enable = 'on';
                    app.DropDown_5.Enable = 'off';
                    app.DropDown_6.Enable = 'off';
                case app.NearestNeighborButton
                    app.DropDown.Enable = 'off';
                    app.DropDown_2.Enable = 'off';
                    app.DropDown_3.Enable = 'on';
                    app.DropDown_4.Enable = 'off';
                    app.DropDown_5.Enable = 'off';
                    app.DropDown_6.Enable = 'off';
                case app.NeuralNetworkButton
                    app.DropDown.Enable = 'off';
                    app.DropDown_2.Enable = 'off';
                    app.DropDown_3.Enable = 'off';
                    app.DropDown_4.Enable = 'off';
                    app.DropDown_5.Enable = 'off';
                    app.DropDown_6.Enable = 'on';
            end
        end

        % Value changed function: ClassNumberDropDown_2
        function ClassNumberDropDown_2ValueChanged(app, event)
            value = app.ClassNumberDropDown_2.Value;
            
        end

        % Value changed function: AcquisitionfunctionDropDown
        function AcquisitionfunctionDropDownValueChanged(app, event)
            value = app.AcquisitionfunctionDropDown.Value;
            app.aqfn = value;
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
            if value == "Optimizable"
                app.OptimizationOptionPanel.Enable = 'on';
            else
                app.OptimizationOptionPanel.Enable = 'off';
            end
        end

        % Value changed function: OptimizerDropDown
        function OptimizerDropDownValueChanged(app, event)
            value = lower(app.OptimizerDropDown.Value);
            switch value
                case 'bayesian'
                    app.optimizer = 'bayesopt';
                otherwise
                    app.optimizer = value;
            end
            
        end

        % Value changed function: IterationsSpinner
        function IterationsSpinnerValueChanged(app, event)
            value = app.IterationsSpinner.Value;
            app.iteration = value;
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            TrainModel.exportModel(app);
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if value == "Optimizable KNN"
                app.OptimizationOptionPanel.Enable = 'on';
            else
                app.OptimizationOptionPanel.Enable = 'off';
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if value == "Optimizable Tree"
                app.OptimizationOptionPanel.Enable = 'on';
            else
                app.OptimizationOptionPanel.Enable = 'off';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == "Optimizable Discriminant"
                app.OptimizationOptionPanel.Enable = 'on';
            else
                app.OptimizationOptionPanel.Enable = 'off';
            end
        end

        % Button pushed function: Preprocessing_ExportButton_2
        function Preprocessing_ExportButton_2Pushed(app, event)
            %try
            TestModel.runPrediction(app);
            %catch
            %    disp("Can not Test neural network")
            %end
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed2(app, event)
            d = uiprogressdlg(app.MSPECAppUIFigure,'Title','Preparing your data','Message','Please wait . . .','Indeterminate','on');
                    pause(0.2);
            app.TabGroup.SelectedTab = app.ModelResultsTab;
        end

        % Button pushed function: Preprocessing_ExportButton_3
        function Preprocessing_ExportButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.ProjectInfoTab;
        end

        % Button pushed function: ClassificationLearnerButton
        function ClassificationLearnerButtonPushed(app, event)
            classificationLearner(app.TrainSet(1:end,1:end-1),app.TrainSet{1:end,end})
        end

        % Selection changed function: DataTypesButtonGroup_3
        function DataTypesButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.DataTypesButtonGroup_3.SelectedObject;
            switch selectedButton
                case app.MassSpectraButton_2
                    %disp("MassSpec");
                    app.Preprocessing_RawMSPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Preprocessing_RawMSPlot.Title.String = "Raw Mass Spectra";
                    app.Preprocessing_PreprocessedMSPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Preprocessing_PreprocessedMSPlot.Title.String = "Pre-Processed Mass Spectra";
                    app.Normalization_ProcessedMSPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Normalization_NormalizedMSPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Detection_PeakDetectionPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Binning_PeakBinningPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Binning_PeakBinningPlot.Title.String = "Common Mass/Charge (M/Z) Locations";
                    app.Binning_AlignedPeakBinningPlot.XLabel.String = "Mass/Charge(m/z)";
                    app.Binning_AlignedPeakBinningPlot.Title.String = "Peaks Aligned to the Common Mass/Charge (M/Z)";
                    app.PeakBinningFinalPlot.XLabel.String = "Mass/Charge Bins";
                case app.RamanSpectraButton_3
                    %disp("Raman");
                    app.Preprocessing_RawMSPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Preprocessing_RawMSPlot.Title.String = "Raw Raman Spectra";
                    app.Preprocessing_PreprocessedMSPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Preprocessing_PreprocessedMSPlot.Title.String = "Pre-Processed Raman Spectra";
                    app.Normalization_ProcessedMSPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Normalization_NormalizedMSPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Detection_PeakDetectionPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Binning_PeakBinningPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Binning_PeakBinningPlot.Title.String = "Common Raman Shift (cm^-1) Locations";
                    app.Binning_AlignedPeakBinningPlot.XLabel.String = "Raman Shift(cm^-1)";
                    app.Binning_AlignedPeakBinningPlot.Title.String = "Peaks Aligned to the Common Raman Shift(cm^-1)";
                    app.PeakBinningFinalPlot.XLabel.String = "Raman Shift Bins";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MSPECAppUIFigure = uifigure('Visible', 'off');
            app.MSPECAppUIFigure.Name = 'MSPEC App';
            app.MSPECAppUIFigure.Icon = 'MS_icon.png';
            app.MSPECAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @MSPECAppUIFigureCloseRequest, true);
            app.Menu = uimenu(app.MSPECAppUIFigure);
            app.Menu.Text = 'Menu';
            app.NewProjectMenu = uimenu(app.Menu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @PushToolClicked, true);
            app.NewProjectMenu.Text = 'New Project';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool9Clicked, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.Menu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @PushTool8Clicked, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @MSPECAppUIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.MSPECAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.ContactUsMenu = uimenu(app.HelpMenu);
            app.ContactUsMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactUsMenuSelected, true);
            app.ContactUsMenu.Text = 'Contact Us';
            app.Toolbar = uitoolbar(app.MSPECAppUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'New Project'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'icons8-plus-math-80.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Open Project'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'Import.png';
            app.PushTool3.Separator = 'on';
            app.PushTool9 = uipushtool(app.Toolbar);
            app.PushTool9.Tag = 'Save';
            app.PushTool9.ClickedCallback = createCallbackFcn(app, @PushTool9Clicked, true);
            app.PushTool9.Icon = 'icons8-save-96.png';
            app.PushTool9.Separator = 'on';
            app.PushTool8 = uipushtool(app.Toolbar);
            app.PushTool8.Tooltip = {'Save as'};
            app.PushTool8.ClickedCallback = createCallbackFcn(app, @PushTool8Clicked, true);
            app.PushTool8.Icon = 'save_as.png';
            app.PushTool8.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = 'icons8-download-96.png';
            app.PushTool4.Separator = 'on';
            app.PushTool7 = uipushtool(app.Toolbar);
            app.PushTool7.ClickedCallback = createCallbackFcn(app, @PushTool7Clicked, true);
            app.PushTool7.Icon = 'icons8-report-file-128.png';
            app.PushTool7.Separator = 'on';
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.ClickedCallback = createCallbackFcn(app, @PushTool6Clicked, true);
            app.PushTool6.Icon = 'icons8-user-manual-80.png';
            app.PushTool6.Separator = 'on';
            app.TabGroup = uitabgroup(app.MSPECAppUIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.WelcometoMSpecLabel = uilabel(app.ImportTab);
            app.WelcometoMSpecLabel.Text = 'Welcome to MSpec !';
            app.ImportyourspectraldatatobeginLabel = uilabel(app.ImportTab);
            app.ImportyourspectraldatatobeginLabel.Text = 'Import your spectral data to begin :)';
            app.CreateNewPreprocessingProjectPanel = uipanel(app.ImportTab);
            app.CreateNewPreprocessingProjectPanel.Title = 'Create New Pre-processing Project';
            app.ProjectNameEditFieldLabel = uilabel(app.CreateNewPreprocessingProjectPanel);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.CreateNewPreprocessingProjectPanel, 'text');
            app.DimensionLabel = uilabel(app.CreateNewPreprocessingProjectPanel);
            app.DimensionLabel.Text = 'Dimension';
            app.WidthField = uieditfield(app.CreateNewPreprocessingProjectPanel, 'numeric');
            app.WidthField.Editable = 'off';
            app.xLabel = uilabel(app.CreateNewPreprocessingProjectPanel);
            app.xLabel.Text = 'x';
            app.HeightField = uieditfield(app.CreateNewPreprocessingProjectPanel, 'numeric');
            app.HeightField.Editable = 'off';
            app.NumberofMassSpectraLabel = uilabel(app.CreateNewPreprocessingProjectPanel);
            app.NumberofMassSpectraLabel.Text = {'Number of '; 'Mass Spectra'};
            app.NumberofMassSpectraEditField = uieditfield(app.CreateNewPreprocessingProjectPanel, 'numeric');
            app.NumberofMassSpectraEditField.Editable = 'off';
            app.Import_CreateProjectButton = uibutton(app.CreateNewPreprocessingProjectPanel, 'push');
            app.Import_CreateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @Import_CreateProjectButtonPushed, true);
            app.Import_CreateProjectButton.Enable = 'off';
            app.Import_CreateProjectButton.Text = 'Create';
            app.DescriptionTextAreaLabel_2 = uilabel(app.CreateNewPreprocessingProjectPanel);
            app.DescriptionTextAreaLabel_2.Text = 'Description';
            app.DescriptionEditField = uitextarea(app.CreateNewPreprocessingProjectPanel);
            app.Import_ImportButton = uibutton(app.CreateNewPreprocessingProjectPanel, 'push');
            app.Import_ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_Pushed, true);
            app.Import_ImportButton.Text = 'Import';
            app.ImportStatusLabel = uilabel(app.CreateNewPreprocessingProjectPanel);
            app.ImportStatusLabel.Text = '';
            app.DataTypesButtonGroup_3 = uibuttongroup(app.CreateNewPreprocessingProjectPanel);
            app.DataTypesButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @DataTypesButtonGroup_3SelectionChanged, true);
            app.DataTypesButtonGroup_3.Title = 'Data Types';
            app.MassSpectraButton_2 = uiradiobutton(app.DataTypesButtonGroup_3);
            app.MassSpectraButton_2.Text = 'Mass Spectra';
            app.MassSpectraButton_2.Value = true;
            app.RamanSpectraButton_3 = uiradiobutton(app.DataTypesButtonGroup_3);
            app.RamanSpectraButton_3.Text = 'Raman Spectra';
            app.FTIRButton_2 = uiradiobutton(app.DataTypesButtonGroup_3);
            app.FTIRButton_2.Text = 'FT-IR';
            app.UVVisibleButton_2 = uiradiobutton(app.DataTypesButtonGroup_3);
            app.UVVisibleButton_2.Text = 'UV-Visible';
            app.otherButton_2 = uiradiobutton(app.DataTypesButtonGroup_3);
            app.otherButton_2.Text = 'other:';
            app.EditField_2 = uieditfield(app.DataTypesButtonGroup_3, 'text');
            app.EditField_2.Enable = 'off';
            app.Panel_4 = uipanel(app.DataTypesButtonGroup_3);
            app.Panel_4.Enable = 'off';
            app.xaxisEditField_2Label = uilabel(app.Panel_4);
            app.xaxisEditField_2Label.Text = 'x-axis';
            app.xaxisEditField_2 = uieditfield(app.Panel_4, 'text');
            app.xaxisEditField_2.Enable = 'off';
            app.Label_9 = uilabel(app.Panel_4);
            app.Label_9.Text = 'Label: ';
            app.yaxisEditField_2Label = uilabel(app.Panel_4);
            app.yaxisEditField_2Label.Text = 'y-axis';
            app.yaxisEditField_2 = uieditfield(app.Panel_4, 'text');
            app.yaxisEditField_2.Enable = 'off';
            app.RecentFilesPanel = uipanel(app.ImportTab);
            app.RecentFilesPanel.Title = 'Recent Files';
            app.RecentFileListBox = uilistbox(app.RecentFilesPanel);
            app.OpenButton = uibutton(app.RecentFilesPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.Image2_2 = uiimage(app.ImportTab);
            app.Image2_2.ImageSource = 'Mspec_Application.png';
            app.PurposeButtonGroup = uibuttongroup(app.ImportTab);
            app.PurposeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PurposeButtonGroupSelectionChanged, true);
            app.PurposeButtonGroup.Title = 'Purpose';
            app.DataPreprocessingButton = uiradiobutton(app.PurposeButtonGroup);
            app.DataPreprocessingButton.Text = 'Data Pre-processing';
            app.DataPreprocessingButton.Value = true;
            app.DataforMLModelingButton = uiradiobutton(app.PurposeButtonGroup);
            app.DataforMLModelingButton.Text = 'Data for ML Modeling';
            app.CreateNewMLPreprocessingProjectPanel_2 = uipanel(app.ImportTab);
            app.CreateNewMLPreprocessingProjectPanel_2.Title = 'Create New ML Pre-processing Project';
            app.TabGroup2 = uitabgroup(app.CreateNewMLPreprocessingProjectPanel_2);
            app.ProjectTitleTab = uitab(app.TabGroup2);
            app.ProjectTitleTab.Title = 'Project Title';
            app.Panel_6 = uipanel(app.ProjectTitleTab);
            app.Panel_6.Enable = 'off';
            app.ProjectNameEditField_2Label_2 = uilabel(app.Panel_6);
            app.ProjectNameEditField_2Label_2.Text = 'Project Name';
            app.ProjectNameEditField_2 = uieditfield(app.Panel_6, 'text');
            app.ProjectNameEditField_2.ValueChangedFcn = createCallbackFcn(app, @ProjectNameEditField_2ValueChanged, true);
            app.ProjectNameEditField_2.ValueChangingFcn = createCallbackFcn(app, @ProjectNameEditField_2ValueChanging, true);
            app.Next_CreateProjectButton_2 = uibutton(app.Panel_6, 'push');
            app.Next_CreateProjectButton_2.ButtonPushedFcn = createCallbackFcn(app, @Next_CreateProjectButton_2Pushed, true);
            app.Next_CreateProjectButton_2.Enable = 'off';
            app.Next_CreateProjectButton_2.Text = 'Next';
            app.DescriptionTextAreaLabel_3 = uilabel(app.Panel_6);
            app.DescriptionTextAreaLabel_3.Text = 'Description';
            app.DescriptionEditField_2 = uitextarea(app.Panel_6);
            app.DataTypesButtonGroup_4 = uibuttongroup(app.Panel_6);
            app.DataTypesButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @DataTypesButtonGroup_4SelectionChanged, true);
            app.DataTypesButtonGroup_4.Title = 'Data Types';
            app.DataTypesButtonGroup_4.ButtonDownFcn = createCallbackFcn(app, @DataTypesButtonGroup_4ButtonDown, true);
            app.MassSpectraButton_3 = uiradiobutton(app.DataTypesButtonGroup_4);
            app.MassSpectraButton_3.Text = 'Mass Spectra';
            app.MassSpectraButton_3.Value = true;
            app.RamanSpectraButton_4 = uiradiobutton(app.DataTypesButtonGroup_4);
            app.RamanSpectraButton_4.Text = 'Raman Spectra';
            app.FTIRButton_3 = uiradiobutton(app.DataTypesButtonGroup_4);
            app.FTIRButton_3.Text = 'FT-IR';
            app.UVVisibleButton_3 = uiradiobutton(app.DataTypesButtonGroup_4);
            app.UVVisibleButton_3.Text = 'UV-Visible';
            app.otherButton_3 = uiradiobutton(app.DataTypesButtonGroup_4);
            app.otherButton_3.Text = 'other:';
            app.EditField_3 = uieditfield(app.DataTypesButtonGroup_4, 'text');
            app.EditField_3.Enable = 'off';
            app.Panel_5 = uipanel(app.DataTypesButtonGroup_4);
            app.Panel_5.Enable = 'off';
            app.xaxisEditField_3Label = uilabel(app.Panel_5);
            app.xaxisEditField_3Label.Text = 'x-axis';
            app.xaxisEditField_3 = uieditfield(app.Panel_5, 'text');
            app.xaxisEditField_3.Enable = 'off';
            app.Label_10 = uilabel(app.Panel_5);
            app.Label_10.Text = 'Label: ';
            app.yaxisEditField_3Label = uilabel(app.Panel_5);
            app.yaxisEditField_3Label.Text = 'y-axis';
            app.yaxisEditField_3 = uieditfield(app.Panel_5, 'text');
            app.yaxisEditField_3.Enable = 'off';
            app.ClassNumberSpinnerLabel = uilabel(app.Panel_6);
            app.ClassNumberSpinnerLabel.Text = 'Class Number';
            app.ClassNumberSpinner = uispinner(app.Panel_6);
            app.ClassNumberSpinner.ValueChangingFcn = createCallbackFcn(app, @ClassNumberSpinnerValueChanging, true);
            app.ClassNumberSpinner.Limits = [1 Inf];
            app.ClassNumberSpinner.Value = 1;
            app.ImportDataTab = uitab(app.TabGroup2);
            app.ImportDataTab.Title = 'Import Data';
            app.Panel_2 = uipanel(app.ImportDataTab);
            app.Panel_2.Enable = 'off';
            app.ClassDropDown_2Label = uilabel(app.Panel_2);
            app.ClassDropDown_2Label.Text = 'Class:';
            app.ClassDropDown_2 = uidropdown(app.Panel_2);
            app.ClassDropDown_2.Items = {'Data Class 1', 'Data Class 2', 'Data Class 3', 'Data Class 4', 'Data Class 5', 'Data Class 6', 'Data Class 7', 'Data Class 8', 'Data Class 9', 'Data Class 10'};
            app.ClassDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ClassDropDown_2ValueChanged, true);
            app.ClassDropDown_2.Value = 'Data Class 1';
            app.Import_ImportButton_3 = uibutton(app.Panel_2, 'push');
            app.Import_ImportButton_3.ButtonPushedFcn = createCallbackFcn(app, @Import_ImportButton_3Pushed, true);
            app.Import_ImportButton_3.Text = 'Import';
            app.ImportStatusLabel_3 = uilabel(app.Panel_2);
            app.ImportStatusLabel_3.Text = 'Please import your file';
            app.ClassNameEditField_2Label = uilabel(app.Panel_2);
            app.ClassNameEditField_2Label.Text = 'Class Name';
            app.ClassNameEditField_2 = uieditfield(app.Panel_2, 'text');
            app.ClassNameEditField_2.ValueChangingFcn = createCallbackFcn(app, @ClassNameEditField_2ValueChanging, true);
            app.NumberofMassSpectraEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.NumberofMassSpectraEditField_3.Editable = 'off';
            app.NumberofMassSpectraEditField_3Label = uilabel(app.Panel_2);
            app.NumberofMassSpectraEditField_3Label.Text = {'Number of '; 'Mass Spectra'};
            app.HeightField_3 = uieditfield(app.Panel_2, 'numeric');
            app.HeightField_3.Editable = 'off';
            app.xLabel_3 = uilabel(app.Panel_2);
            app.xLabel_3.Text = 'x';
            app.WidthField_3 = uieditfield(app.Panel_2, 'numeric');
            app.WidthField_3.Editable = 'off';
            app.DimensionLabel_3 = uilabel(app.Panel_2);
            app.DimensionLabel_3.Text = 'Dimension';
            app.Import_CreateProjectButton_4 = uibutton(app.Panel_2, 'push');
            app.Import_CreateProjectButton_4.ButtonPushedFcn = createCallbackFcn(app, @Import_CreateProjectButton_4Pushed, true);
            app.Import_CreateProjectButton_4.Enable = 'off';
            app.Import_CreateProjectButton_4.Text = 'Create';
            app.PleasefilltheclassnameLabel = uilabel(app.Panel_2);
            app.PleasefilltheclassnameLabel.Visible = 'off';
            app.PleasefilltheclassnameLabel.Text = 'Please fill the class name !';
            app.PreprocessingTab = uitab(app.TabGroup);
            app.PreprocessingTab.Title = 'Pre-processing';
            app.Preprocessing_PreprocessedMSPlot = uiaxes(app.PreprocessingTab);
            title(app.Preprocessing_PreprocessedMSPlot, 'Pre-Processed Mass Spectra')
            xlabel(app.Preprocessing_PreprocessedMSPlot, 'Mass/Charge (m/z)')
            ylabel(app.Preprocessing_PreprocessedMSPlot, 'Relative Intensity')
            app.Preprocessing_RawMSPlot = uiaxes(app.PreprocessingTab);
            title(app.Preprocessing_RawMSPlot, 'Raw Mass Spectra')
            xlabel(app.Preprocessing_RawMSPlot, 'Mass/Charge (m/z)')
            ylabel(app.Preprocessing_RawMSPlot, 'Relative Intensity')
            app.PreProcessingToolLabel = uilabel(app.PreprocessingTab);
            app.PreProcessingToolLabel.Text = 'Pre-Processing Tool';
            app.BaselineCorrectionPanel = uipanel(app.PreprocessingTab);
            app.BaselineCorrectionPanel.Title = 'Baseline Correction';
            app.Preprocessing_WindowSizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_WindowSizeEditField.Value = 0.5;
            app.WindowSizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.Preprocessing_StepsizeEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_StepsizeEditField.Value = 0.5;
            app.StepsizeEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.Preprocessing_QuantilevalueEditField = uieditfield(app.BaselineCorrectionPanel, 'numeric');
            app.Preprocessing_QuantilevalueEditField.Limits = [0 Inf];
            app.Preprocessing_QuantilevalueEditField.Value = 0.5;
            app.QuantilevalueEditFieldLabel = uilabel(app.BaselineCorrectionPanel);
            app.QuantilevalueEditFieldLabel.Text = 'Quantile value';
            app.PeakAlignmentPanel = uipanel(app.PreprocessingTab);
            app.PeakAlignmentPanel.Title = 'Peak Alignment';
            app.Preprocessing_ReferenceSpectrumEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_ReferenceSpectrumEditField.Value = 2;
            app.ReferenceSpectrumEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.ReferenceSpectrumEditFieldLabel.Text = 'Reference Spectrum';
            app.Preprocessing_MinimumsegementsizeallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MinimumsegementsizeallowedEditField.Value = 10;
            app.MinimumsegmentsizeallowedLabel = uilabel(app.PeakAlignmentPanel);
            app.MinimumsegmentsizeallowedLabel.Text = 'Minimum segment size allowed';
            app.Preprocessing_MaximumshiftallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MaximumshiftallowedEditField.Value = 50;
            app.MaximumshiftallowedEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.MaximumshiftallowedEditFieldLabel.Text = 'Maximum shift allowed';
            app.PlotPanel = uipanel(app.PreprocessingTab);
            app.PlotPanel.Title = 'Plot';
            app.SpectrumtodisplayEditFieldLabel = uilabel(app.PlotPanel);
            app.SpectrumtodisplayEditFieldLabel.Text = 'Spectrum to display';
            app.Preprocessing_SpectrumtodisplayEditField = uieditfield(app.PlotPanel, 'text');
            app.Preprocessing_SectionofinterestLabel = uilabel(app.PlotPanel);
            app.Preprocessing_SectionofinterestLabel.Text = 'Section of interest:';
            app.StartingpointEditFieldLabel = uilabel(app.PlotPanel);
            app.StartingpointEditFieldLabel.Text = 'Starting point';
            app.Preprocessing_StartingpointEditField = uieditfield(app.PlotPanel, 'text');
            app.EndingpointEditFieldLabel = uilabel(app.PlotPanel);
            app.EndingpointEditFieldLabel.Text = 'Ending point';
            app.Preprocessing_EndingpointEditField = uieditfield(app.PlotPanel, 'text');
            app.Preprocessing_PlotButton = uibutton(app.PlotPanel, 'push');
            app.Preprocessing_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_PlotButtonPushed, true);
            app.Preprocessing_PlotButton.Text = 'Plot';
            app.Preprocessing_ExportButton = uibutton(app.PreprocessingTab, 'push');
            app.Preprocessing_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_ExportButtonPushed, true);
            app.Preprocessing_ExportButton.Text = 'Export';
            app.Preprocessing_SaveButton = uibutton(app.PreprocessingTab, 'push');
            app.Preprocessing_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_SaveButtonPushed, true);
            app.Preprocessing_SaveButton.Text = 'Next';
            app.NormalizationTab = uitab(app.TabGroup);
            app.NormalizationTab.Title = 'Normalization';
            app.Normalization_ProcessedMSPlot = uiaxes(app.NormalizationTab);
            title(app.Normalization_ProcessedMSPlot, 'Aligned Spectra')
            xlabel(app.Normalization_ProcessedMSPlot, 'Mass/Charge (m/z)')
            ylabel(app.Normalization_ProcessedMSPlot, 'Relative Intensity')
            app.Normalization_NormalizedMSPlot = uiaxes(app.NormalizationTab);
            title(app.Normalization_NormalizedMSPlot, 'Normalized Spectra')
            xlabel(app.Normalization_NormalizedMSPlot, 'Mass/Charge (m/z)')
            ylabel(app.Normalization_NormalizedMSPlot, 'Relative Intensity')
            app.NormalizationMethodsButtonGroup = uibuttongroup(app.NormalizationTab);
            app.NormalizationMethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalizationMethodsButtonGroupSelectionChanged, true);
            app.NormalizationMethodsButtonGroup.Title = 'Normalization Methods';
            app.Normalization_ioncountsButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_ioncountsButton.Text = 'ion counts';
            app.Normalization_ioncountsButton.Value = true;
            app.Normalization_ReferencePeakButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_ReferencePeakButton.Text = 'Reference Peak';
            app.pvalueSpinnerLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.pvalueSpinnerLabel.Enable = 'off';
            app.pvalueSpinnerLabel.Text = 'p-value';
            app.Normalization_pvalueSpinner = uispinner(app.NormalizationMethodsButtonGroup);
            app.Normalization_pvalueSpinner.Limits = [1 Inf];
            app.Normalization_pvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @Normalization_pvalueSpinnerValueChanged, true);
            app.Normalization_pvalueSpinner.Enable = 'off';
            app.Normalization_pvalueSpinner.Value = 1;
            app.Normalization_pnormButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_pnormButton.Text = 'p-norm';
            app.Normalization_MedianButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_MedianButton.Text = 'Median';
            app.Normalization_NoiseLevelButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_NoiseLevelButton.Text = 'Noise Level';
            app.Normalize_StartButton = uibutton(app.NormalizationMethodsButtonGroup, 'push');
            app.Normalize_StartButton.ButtonPushedFcn = createCallbackFcn(app, @Normalize_StartButtonPushed, true);
            app.Normalize_StartButton.Text = 'Start';
            app.ReferenceMZLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.ReferenceMZLabel.Text = {'Reference'; 'M/Z'};
            app.Normalization_ReferenceMZEditField = uieditfield(app.NormalizationMethodsButtonGroup, 'numeric');
            app.Normalization_ReferenceMZEditField.ValueDisplayFormat = '%5.4f';
            app.Normalization_ReferenceMZEditField.ValueChangedFcn = createCallbackFcn(app, @Normalization_ReferenceMZEditFieldValueChanged, true);
            app.Normalization_ReferenceMZEditField.Editable = 'off';
            app.Normalization_EditRefMZ = uibutton(app.NormalizationMethodsButtonGroup, 'push');
            app.Normalization_EditRefMZ.ButtonPushedFcn = createCallbackFcn(app, @Normalization_EditRefMZPushed, true);
            app.Normalization_EditRefMZ.Text = 'Edit';
            app.Normalization_MaxIntensityButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_MaxIntensityButton.Text = 'Maximum Intensity';
            app.ViewOptionButtonGroup = uibuttongroup(app.NormalizationTab);
            app.ViewOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewOptionButtonGroupSelectionChanged, true);
            app.ViewOptionButtonGroup.Title = ' View Option';
            app.Normalization_AllSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Normalization_AllSpectraButton.Text = 'All Spectra';
            app.Normalization_AllSpectraButton.Value = true;
            app.Normalization_SingleSpectrumButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Normalization_SingleSpectrumButton.Text = 'Single Spectrum';
            app.SamplePointSpinnerLabel = uilabel(app.ViewOptionButtonGroup);
            app.SamplePointSpinnerLabel.Enable = 'off';
            app.SamplePointSpinnerLabel.Text = 'Sample Point';
            app.Normalization_SamplePointSpinner = uispinner(app.ViewOptionButtonGroup);
            app.Normalization_SamplePointSpinner.Limits = [1 80];
            app.Normalization_SamplePointSpinner.ValueChangedFcn = createCallbackFcn(app, @Normalization_SamplePointSpinnerValueChanged, true);
            app.Normalization_SamplePointSpinner.Enable = 'off';
            app.Normalization_SamplePointSpinner.Value = 1;
            app.Normalization_SelectDataTable = uitable(app.ViewOptionButtonGroup);
            app.Normalization_SelectDataTable.ColumnName = {'Sample Point'; 'Display'};
            app.Normalization_SelectDataTable.RowName = {};
            app.Normalization_SelectDataTable.ColumnSortable = true;
            app.Normalization_SelectDataTable.ColumnEditable = [false true];
            app.Normalization_SelectDataTable.CellEditCallback = createCallbackFcn(app, @Normalization_SelectDataTableCellEdit, true);
            app.Normalization_SelectDataTable.Enable = 'off';
            app.Normalization_MultipleSpectraButton = uiradiobutton(app.ViewOptionButtonGroup);
            app.Normalization_MultipleSpectraButton.Text = 'Multiple Spectra';
            app.ExportFilesPanel = uipanel(app.NormalizationTab);
            app.ExportFilesPanel.Title = 'Export Files';
            app.Normalization_ExportCSVNormalizedDataButton = uibutton(app.ExportFilesPanel, 'push');
            app.Normalization_ExportCSVNormalizedDataButton.ButtonPushedFcn = createCallbackFcn(app, @Normalization_ExportCSVNormalizedDataButtonPushed, true);
            app.Normalization_ExportCSVNormalizedDataButton.Text = 'Export Normalized Data ';
            app.Normalization_SaveButton = uibutton(app.ExportFilesPanel, 'push');
            app.Normalization_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Normalization_SaveButtonPushed, true);
            app.Normalization_SaveButton.Text = 'Next';
            app.Normalization_DataTable = uitable(app.NormalizationTab);
            app.Normalization_DataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Normalization_DataTable.RowName = {};
            app.DataNormalizationLabel = uilabel(app.NormalizationTab);
            app.DataNormalizationLabel.Text = 'Data Normalization';
            app.PeakDetectionTab = uitab(app.TabGroup);
            app.PeakDetectionTab.Title = 'Peak Detection';
            app.Detection_PeakDetectionPlot = uiaxes(app.PeakDetectionTab);
            title(app.Detection_PeakDetectionPlot, 'Peak Detection')
            xlabel(app.Detection_PeakDetectionPlot, 'Mass/Charge (m/z)')
            ylabel(app.Detection_PeakDetectionPlot, 'Relative Intensity')
            app.Binning_PeakBinningPlot = uiaxes(app.PeakDetectionTab);
            title(app.Binning_PeakBinningPlot, 'Common Mass/Charge (M/Z) Locations')
            xlabel(app.Binning_PeakBinningPlot, 'Mass/Charge (m/z)')
            ylabel(app.Binning_PeakBinningPlot, 'Relative Intensity')
            app.Binning_AlignedPeakBinningPlot = uiaxes(app.PeakDetectionTab);
            title(app.Binning_AlignedPeakBinningPlot, {'Peaks Aligned to the'; 'Common Mass/Charge (M/Z)'})
            xlabel(app.Binning_AlignedPeakBinningPlot, 'Mass/Charge (m/z)')
            ylabel(app.Binning_AlignedPeakBinningPlot, 'Relative Intensity')
            app.PeakDetectionPanel = uipanel(app.PeakDetectionTab);
            app.PeakDetectionPanel.Title = 'Peak Detection';
            app.BASEEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.BASEEditFieldLabel.Enable = 'off';
            app.BASEEditFieldLabel.Text = 'BASE';
            app.Detection_BASEEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.Detection_BASEEditField.Enable = 'off';
            app.Detection_BASEEditField.Value = 12;
            app.HEIGHTFILTEREditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.HEIGHTFILTEREditFieldLabel.Enable = 'off';
            app.HEIGHTFILTEREditFieldLabel.Text = 'HEIGHTFILTER';
            app.Detection_HEIGHTFILTEREditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.Detection_HEIGHTFILTEREditField.Enable = 'off';
            app.Detection_HEIGHTFILTEREditField.Value = 1;
            app.MULTIPLIEREditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.MULTIPLIEREditFieldLabel.Enable = 'off';
            app.MULTIPLIEREditFieldLabel.Text = 'MULTIPLIER';
            app.Detection_MULTIPLIEREditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.Detection_MULTIPLIEREditField.Enable = 'off';
            app.Detection_MULTIPLIEREditField.Value = 10;
            app.PeakThresholdEditFieldLabel = uilabel(app.PeakDetectionPanel);
            app.PeakThresholdEditFieldLabel.Enable = 'off';
            app.PeakThresholdEditFieldLabel.Text = 'Peak Threshold';
            app.Detection_PeakThresholdEditField = uieditfield(app.PeakDetectionPanel, 'numeric');
            app.Detection_PeakThresholdEditField.Enable = 'off';
            app.Detection_PeakThresholdEditField.Value = 300;
            app.Detection_StartDetectionButton = uibutton(app.PeakDetectionPanel, 'push');
            app.Detection_StartDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @Detection_StartDetectionButtonPushed, true);
            app.Detection_StartDetectionButton.Text = 'Start';
            app.MethodDropDownLabel_2 = uilabel(app.PeakDetectionPanel);
            app.MethodDropDownLabel_2.Text = 'Method';
            app.Detection_MethodDropDown = uidropdown(app.PeakDetectionPanel);
            app.Detection_MethodDropDown.Items = {'Auto', 'Self Adjustments'};
            app.Detection_MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @Detection_MethodDropDownValueChanged, true);
            app.Detection_MethodDropDown.Value = 'Auto';
            app.BiningPanel = uipanel(app.PeakDetectionTab);
            app.BiningPanel.Title = 'Bining';
            app.MethodDropDownLabel = uilabel(app.BiningPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.Binning_MethodDropDown = uidropdown(app.BiningPanel);
            app.Binning_MethodDropDown.Items = {'Hierarchical Clustering', 'Dynamic Programming'};
            app.Binning_MethodDropDown.ItemsData = {'1'};
            app.Binning_MethodDropDown.Value = '1';
            app.CriterionDropDownLabel = uilabel(app.BiningPanel);
            app.CriterionDropDownLabel.Text = 'Criterion';
            app.Binning_CriterionDropDown = uidropdown(app.BiningPanel);
            app.Binning_CriterionDropDown.Items = {'DISTANCE'};
            app.Binning_CriterionDropDown.ItemsData = {'distance'};
            app.Binning_CriterionDropDown.Value = 'distance';
            app.Detection_StartDetectionButton_2 = uibutton(app.BiningPanel, 'push');
            app.Detection_StartDetectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @Detection_StartDetectionButton_2Pushed, true);
            app.Detection_StartDetectionButton_2.Text = 'Start';
            app.CUTOFFSpinnerLabel = uilabel(app.BiningPanel);
            app.CUTOFFSpinnerLabel.Text = 'CUTOFF';
            app.CUTOFFSpinner = uispinner(app.BiningPanel);
            app.CUTOFFSpinner.Step = 0.1;
            app.CUTOFFSpinner.Limits = [0 Inf];
            app.CUTOFFSpinner.ValueDisplayFormat = '%5.2f';
            app.CUTOFFSpinner.Value = 1;
            app.ExportPeakListButton = uibutton(app.PeakDetectionTab, 'push');
            app.ExportPeakListButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPeakListButtonPushed, true);
            app.ExportPeakListButton.Text = 'Export  Peak List';
            app.PeakDetectionLabel = uilabel(app.PeakDetectionTab);
            app.PeakDetectionLabel.Text = 'Peak Detection';
            app.SpectrumtodisplayoptionalLabel = uilabel(app.PeakDetectionTab);
            app.SpectrumtodisplayoptionalLabel.Text = {'Spectrum'; 'to display'; '(optional)'};
            app.Detection_SpectrumtodisplayEditField = uieditfield(app.PeakDetectionTab, 'text');
            app.NextButton = uibutton(app.PeakDetectionTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SampleAlignmentPanel = uipanel(app.PeakDetectionTab);
            app.SampleAlignmentPanel.Title = 'Sample Alignment';
            app.MethodDropDownLabel_3 = uilabel(app.SampleAlignmentPanel);
            app.MethodDropDownLabel_3.Text = 'Method';
            app.Binning_MethodDropDown_2 = uidropdown(app.SampleAlignmentPanel);
            app.Binning_MethodDropDown_2.Items = {'Auto'};
            app.Binning_MethodDropDown_2.ItemsData = {'1'};
            app.Binning_MethodDropDown_2.Value = '1';
            app.Detection_StartDetectionButton_3 = uibutton(app.SampleAlignmentPanel, 'push');
            app.Detection_StartDetectionButton_3.ButtonPushedFcn = createCallbackFcn(app, @Detection_StartDetectionButton_3Pushed, true);
            app.Detection_StartDetectionButton_3.Text = 'Start';
            app.MaximumallowabledistanceLabel = uilabel(app.SampleAlignmentPanel);
            app.MaximumallowabledistanceLabel.Enable = 'off';
            app.MaximumallowabledistanceLabel.Text = {'Maximum'; 'allowable distance '};
            app.Detection_BANDEditField = uieditfield(app.SampleAlignmentPanel, 'numeric');
            app.Detection_BANDEditField.Enable = 'off';
            app.Detection_BANDEditField.Value = 12;
            app.TotalnoofpeaksEditFieldLabel = uilabel(app.PeakDetectionTab);
            app.TotalnoofpeaksEditFieldLabel.Text = 'Total no. of peaks';
            app.TotalnoofpeaksEditField = uieditfield(app.PeakDetectionTab, 'numeric');
            app.TotalnoofpeaksEditField.Editable = 'off';
            app.TotalnoofcommonpeaksEditFieldLabel = uilabel(app.PeakDetectionTab);
            app.TotalnoofcommonpeaksEditFieldLabel.Text = 'Total no. of common peaks';
            app.TotalnoofcommonpeaksEditField = uieditfield(app.PeakDetectionTab, 'numeric');
            app.TotalnoofcommonpeaksEditField.Editable = 'off';
            app.ExportCMZButton = uibutton(app.PeakDetectionTab, 'push');
            app.ExportCMZButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCMZButtonPushed, true);
            app.ExportCMZButton.Text = 'Export  CMZ';
            app.PeakBinningTab = uitab(app.TabGroup);
            app.PeakBinningTab.Title = 'Peak Binning';
            app.PeakBinningFinalPlot = uiaxes(app.PeakBinningTab);
            title(app.PeakBinningFinalPlot, 'Peak Binning')
            xlabel(app.PeakBinningFinalPlot, 'Mass/Charge Bins')
            ylabel(app.PeakBinningFinalPlot, 'Accummulative Intensity')
            zlabel(app.PeakBinningFinalPlot, 'Z')
            app.Binning_EdgeListPlot = uiaxes(app.PeakBinningTab);
            xlabel(app.Binning_EdgeListPlot, 'Mass/Charge (m/z)')
            ylabel(app.Binning_EdgeListPlot, 'Y')
            zlabel(app.Binning_EdgeListPlot, 'Z')
            app.Binning_EdgeListPlot.YLim = [0 2.22044604925031e-16];
            app.PeakBinningPanel = uipanel(app.PeakBinningTab);
            app.PeakBinningPanel.Title = 'Peak Binning';
            app.Binning_StartBinningButton = uibutton(app.PeakBinningPanel, 'push');
            app.Binning_StartBinningButton.ButtonPushedFcn = createCallbackFcn(app, @Binning_StartBinningButtonPushed, true);
            app.Binning_StartBinningButton.Text = 'Start';
            app.MethodDropDownLabel_4 = uilabel(app.PeakBinningPanel);
            app.MethodDropDownLabel_4.Text = 'Method';
            app.Binning_BinningMethod = uidropdown(app.PeakBinningPanel);
            app.Binning_BinningMethod.Items = {'Strict', 'Relaxed'};
            app.Binning_BinningMethod.Value = 'Strict';
            app.MaximumnoofpeaksineachbinLabel = uilabel(app.PeakBinningPanel);
            app.MaximumnoofpeaksineachbinLabel.Text = {'Maximum no. of '; 'peaks in each bin'};
            app.Binning_NumberofBinsSpinner = uispinner(app.PeakBinningPanel);
            app.Binning_NumberofBinsSpinner.Limits = [1 2];
            app.Binning_NumberofBinsSpinner.Value = 1;
            app.ToleranceEditFieldLabel = uilabel(app.PeakBinningPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.Binning_ToleranceEditField = uieditfield(app.PeakBinningPanel, 'numeric');
            app.Binning_ToleranceEditField.Limits = [0 Inf];
            app.Binning_ExportButton = uibutton(app.PeakBinningPanel, 'push');
            app.Binning_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @Binning_ExportButtonPushed, true);
            app.Binning_ExportButton.Text = 'Export Data';
            app.Binning_ImportEdgesCheckBox = uicheckbox(app.PeakBinningPanel);
            app.Binning_ImportEdgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @Binning_ImportEdgesCheckBoxValueChanged, true);
            app.Binning_ImportEdgesCheckBox.Text = '  Import Edges';
            app.Binning_ImportEdgeButton = uibutton(app.PeakBinningPanel, 'push');
            app.Binning_ImportEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @Binning_ImportEdgeButtonPushed, true);
            app.Binning_ImportEdgeButton.Icon = 'file_icon.png';
            app.Binning_ImportEdgeButton.Enable = 'off';
            app.Binning_ImportEdgeButton.Text = '';
            app.Binning_FileLabel = uilabel(app.PeakBinningPanel);
            app.Binning_FileLabel.Text = 'Choose File . . .';
            app.Binning_ExportBinButton = uibutton(app.PeakBinningPanel, 'push');
            app.Binning_ExportBinButton.ButtonPushedFcn = createCallbackFcn(app, @Binning_ExportBinButtonPushed, true);
            app.Binning_ExportBinButton.Text = 'Export Bin Edges';
            app.Binning_ViewOptionButtonGroup = uibuttongroup(app.PeakBinningTab);
            app.Binning_ViewOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Binning_ViewOptionButtonGroupSelectionChanged, true);
            app.Binning_ViewOptionButtonGroup.Title = ' View Option';
            app.Binning_AllSpectraButton = uiradiobutton(app.Binning_ViewOptionButtonGroup);
            app.Binning_AllSpectraButton.Text = 'All Spectra';
            app.Binning_AllSpectraButton.Value = true;
            app.Binning_SingleSpectrumButton = uiradiobutton(app.Binning_ViewOptionButtonGroup);
            app.Binning_SingleSpectrumButton.Text = 'Single Spectrum';
            app.SamplePointSpinnerLabel_2 = uilabel(app.Binning_ViewOptionButtonGroup);
            app.SamplePointSpinnerLabel_2.Enable = 'off';
            app.SamplePointSpinnerLabel_2.Text = 'Sample Point';
            app.Binning_SamplePointSpinner = uispinner(app.Binning_ViewOptionButtonGroup);
            app.Binning_SamplePointSpinner.Limits = [1 80];
            app.Binning_SamplePointSpinner.ValueChangedFcn = createCallbackFcn(app, @Binning_SamplePointSpinnerValueChanged, true);
            app.Binning_SamplePointSpinner.Enable = 'off';
            app.Binning_SamplePointSpinner.Value = 1;
            app.Binning_SelectDataTable = uitable(app.Binning_ViewOptionButtonGroup);
            app.Binning_SelectDataTable.ColumnName = {'Sample'; 'Display'};
            app.Binning_SelectDataTable.RowName = {};
            app.Binning_SelectDataTable.ColumnSortable = true;
            app.Binning_SelectDataTable.ColumnEditable = [false true];
            app.Binning_SelectDataTable.CellEditCallback = createCallbackFcn(app, @Binning_SelectDataTableCellEdit, true);
            app.Binning_SelectDataTable.Enable = 'off';
            app.Binning_MultipleSpectraButton = uiradiobutton(app.Binning_ViewOptionButtonGroup);
            app.Binning_MultipleSpectraButton.Text = 'Multiple Spectra';
            app.CMZLabel = uilabel(app.PeakBinningTab);
            app.CMZLabel.Text = 'CMZ';
            app.CalculatedEdgesLabel = uilabel(app.PeakBinningTab);
            app.CalculatedEdgesLabel.Text = {'Calculated'; 'Edges'};
            app.Label_2 = uilabel(app.PeakBinningTab);
            app.Label_2.Text = '.';
            app.Label_3 = uilabel(app.PeakBinningTab);
            app.Label_3.Text = '*';
            app.PeakBinningLabel = uilabel(app.PeakBinningTab);
            app.PeakBinningLabel.Text = 'Peak Binning';
            app.Binning_Table = uitable(app.PeakBinningTab);
            app.Binning_Table.ColumnName = '';
            app.Binning_Table.RowName = {};
            app.Binning_StartBinningButton_2 = uibutton(app.PeakBinningTab, 'push');
            app.Binning_StartBinningButton_2.ButtonPushedFcn = createCallbackFcn(app, @Binning_StartBinningButton_2Pushed, true);
            app.Binning_StartBinningButton_2.Text = 'Next';
            app.DataSplittingTab = uitab(app.TabGroup);
            app.DataSplittingTab.Title = 'Data Splitting';
            app.ExportDataForModelAllButton = uibutton(app.DataSplittingTab, 'push');
            app.ExportDataForModelAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExportForModelButtonPushed, true);
            app.ExportDataForModelAllButton.Enable = 'off';
            app.ExportDataForModelAllButton.Text = 'Export Data For Model(All)';
            app.DataInformationPanel = uipanel(app.DataSplittingTab);
            app.DataInformationPanel.Title = 'Data Information';
            app.ProjectNameLabel = uilabel(app.DataInformationPanel);
            app.ProjectNameLabel.Text = 'Project Name: ';
            app.ClassNumberLabel = uilabel(app.DataInformationPanel);
            app.ClassNumberLabel.Text = 'Class Number:';
            app.ClassNumberEditField = uieditfield(app.DataInformationPanel, 'text');
            app.ClassNumberEditField.Editable = 'off';
            app.TotalSpectraLabel = uilabel(app.DataInformationPanel);
            app.TotalSpectraLabel.Text = 'Total Spectra:';
            app.TotalSpectraEditField = uieditfield(app.DataInformationPanel, 'text');
            app.TotalSpectraEditField.Editable = 'off';
            app.ClassNameLabel = uilabel(app.DataInformationPanel);
            app.ClassNameLabel.Text = 'Class Name:';
            app.ClassNameListBox = uilistbox(app.DataInformationPanel);
            app.ClassNameListBox.Items = {};
            app.ClassNameListBox.Enable = 'off';
            app.ClassNameListBox.Value = {};
            app.FeatureNumberLabel = uilabel(app.DataInformationPanel);
            app.FeatureNumberLabel.Text = 'Feature Number:';
            app.FeatureNumberEditField = uieditfield(app.DataInformationPanel, 'text');
            app.FeatureNumberEditField.Editable = 'off';
            app.StartButton_3 = uibutton(app.DataInformationPanel, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_3Pushed, true);
            app.StartButton_3.Enable = 'off';
            app.StartButton_3.Text = 'Start';
            app.DoneLabel = uilabel(app.DataInformationPanel);
            app.DoneLabel.Visible = 'off';
            app.DoneLabel.Text = 'Done !';
            app.ExportPreprocessedDataforMLButton = uibutton(app.DataInformationPanel, 'push');
            app.ExportPreprocessedDataforMLButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPreprocessedDataforMLButtonPushed, true);
            app.ExportPreprocessedDataforMLButton.Enable = 'off';
            app.ExportPreprocessedDataforMLButton.Text = {'Export Pre-processed '; 'Data for ML'};
            app.DetailProtectagainstoverfittingbypartitioningthedatasetLabel_2 = uilabel(app.DataInformationPanel);
            app.DetailProtectagainstoverfittingbypartitioningthedatasetLabel_2.Text = 'Press "Start" button to assign the class labels.';
            app.NextButton_2 = uibutton(app.DataSplittingTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.DatasplittingPanel = uipanel(app.DataSplittingTab);
            app.DatasplittingPanel.Title = 'Data splitting';
            app.TrainSetEditFieldLabel = uilabel(app.DatasplittingPanel);
            app.TrainSetEditFieldLabel.Text = 'TrainSet';
            app.TrainSetEditField = uieditfield(app.DatasplittingPanel, 'numeric');
            app.TestSetEditFieldLabel = uilabel(app.DatasplittingPanel);
            app.TestSetEditFieldLabel.Text = 'TestSet';
            app.TestSetEditField = uieditfield(app.DatasplittingPanel, 'numeric');
            app.Label_5 = uilabel(app.DatasplittingPanel);
            app.Label_5.Text = ':';
            app.Label_6 = uilabel(app.DatasplittingPanel);
            app.Label_6.Text = '%';
            app.Label_7 = uilabel(app.DatasplittingPanel);
            app.Label_7.Text = '%';
            app.SplitButton = uibutton(app.DatasplittingPanel, 'push');
            app.SplitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitButton.Enable = 'off';
            app.SplitButton.Text = 'Split';
            app.TrainSetNumberofSpectraEditFieldLabel = uilabel(app.DatasplittingPanel);
            app.TrainSetNumberofSpectraEditFieldLabel.Text = 'TrainSet Number of Spectra';
            app.TrainSetNumberofSpectraEditField = uieditfield(app.DatasplittingPanel, 'numeric');
            app.TrainSetNumberofSpectraEditField.Editable = 'off';
            app.TestSetNumberofSpectraEditFieldLabel = uilabel(app.DatasplittingPanel);
            app.TestSetNumberofSpectraEditFieldLabel.Text = 'TestSet Number of Spectra';
            app.TestSetNumberofSpectraEditField = uieditfield(app.DatasplittingPanel, 'numeric');
            app.TestSetNumberofSpectraEditField.Editable = 'off';
            app.ExportSplitDataButton = uibutton(app.DatasplittingPanel, 'push');
            app.ExportSplitDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSplitDataButtonPushed, true);
            app.ExportSplitDataButton.Enable = 'off';
            app.ExportSplitDataButton.Text = 'Export Split Data';
            app.MLPreprocessingLabel = uilabel(app.DataSplittingTab);
            app.MLPreprocessingLabel.Text = 'ML Pre-processing';
            app.TrainSetPanel = uipanel(app.DataSplittingTab);
            app.TrainSetPanel.Title = 'TrainSet';
            app.Normalization_DataTable_2 = uitable(app.TrainSetPanel);
            app.Normalization_DataTable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'};
            app.Normalization_DataTable_2.RowName = {};
            app.TestSetPanel = uipanel(app.DataSplittingTab);
            app.TestSetPanel.Title = 'TestSet';
            app.Normalization_DataTable_3 = uitable(app.TestSetPanel);
            app.Normalization_DataTable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'};
            app.Normalization_DataTable_3.RowName = {};
            app.ModelTrainingTab = uitab(app.TabGroup);
            app.ModelTrainingTab.Title = 'Model Training';
            app.TrainModelButton = uibutton(app.ModelTrainingTab, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed2, true);
            app.TrainModelButton.Enable = 'off';
            app.TrainModelButton.Text = 'Train Model';
            app.ValidationButtonGroup = uibuttongroup(app.ModelTrainingTab);
            app.ValidationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ValidationButtonGroupSelectionChanged, true);
            app.ValidationButtonGroup.Title = 'Validation';
            app.ValidationButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ValidationButtonGroupButtonDown, true);
            app.CrossValidationButton = uiradiobutton(app.ValidationButtonGroup);
            app.CrossValidationButton.Text = 'Cross-Validation';
            app.CrossValidationButton.Value = true;
            app.HoldoutValidationButton = uiradiobutton(app.ValidationButtonGroup);
            app.HoldoutValidationButton.Text = 'Holdout Validation';
            app.ResubstitutionValidationButton = uiradiobutton(app.ValidationButtonGroup);
            app.ResubstitutionValidationButton.Text = 'Resubstitution Validation';
            app.CrossValidationfoldsSpinnerLabel = uilabel(app.ValidationButtonGroup);
            app.CrossValidationfoldsSpinnerLabel.Text = 'Cross-Validation folds:';
            app.CrossValidationfoldsSpinner = uispinner(app.ValidationButtonGroup);
            app.CrossValidationfoldsSpinner.Limits = [2 Inf];
            app.CrossValidationfoldsSpinner.Value = 2;
            app.PercentheldoutLabel = uilabel(app.ValidationButtonGroup);
            app.PercentheldoutLabel.Text = 'Percent held out:';
            app.PercentheldoutSpinner = uispinner(app.ValidationButtonGroup);
            app.PercentheldoutSpinner.Limits = [0 Inf];
            app.PercentheldoutSpinner.Enable = 'off';
            app.PercentheldoutSpinner.Value = 1;
            app.DetailProtectagainstoverfittingbypartitioningthedatasetLabel = uilabel(app.ValidationButtonGroup);
            app.DetailProtectagainstoverfittingbypartitioningthedatasetLabel.Text = {'Protects against overfitting by partitioning the data set'; 'into folds and estimating accuracy on each fold.'};
            app.RecommendedforlargedatasetsLabel = uilabel(app.ValidationButtonGroup);
            app.RecommendedforlargedatasetsLabel.Text = 'Recommended for large data sets.';
            app.DetailNoprotectionagainstoverfittingTheappusesallthedataLabel = uilabel(app.ValidationButtonGroup);
            app.DetailNoprotectionagainstoverfittingTheappusesallthedataLabel.Text = {'No protection against overfitting.'; 'The app uses all the data for both training and validation.'};
            app.ModelSelectionButtonGroup = uibuttongroup(app.ModelTrainingTab);
            app.ModelSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelSelectionButtonGroupSelectionChanged, true);
            app.ModelSelectionButtonGroup.Title = 'Model Selection';
            app.DecisionTreesButton = uitogglebutton(app.ModelSelectionButtonGroup);
            app.DecisionTreesButton.Text = 'Decision Trees';
            app.DiscriminantAnalysisButton = uitogglebutton(app.ModelSelectionButtonGroup);
            app.DiscriminantAnalysisButton.Text = 'Discriminant Analysis';
            app.NaiveBayesButton = uitogglebutton(app.ModelSelectionButtonGroup);
            app.NaiveBayesButton.Text = 'Naive Bayes';
            app.SupportVectorMachinesButton = uitogglebutton(app.ModelSelectionButtonGroup);
            app.SupportVectorMachinesButton.Text = 'Support Vector Machines';
            app.SupportVectorMachinesButton.Value = true;
            app.NearestNeighborButton = uitogglebutton(app.ModelSelectionButtonGroup);
            app.NearestNeighborButton.Text = 'Nearest Neighbor';
            app.NeuralNetworkButton = uitogglebutton(app.ModelSelectionButtonGroup);
            app.NeuralNetworkButton.Text = 'Neural Network';
            app.DropDown = uidropdown(app.ModelSelectionButtonGroup);
            app.DropDown.Items = {'Linear Discriminant'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Linear Discriminant';
            app.DropDown_2 = uidropdown(app.ModelSelectionButtonGroup);
            app.DropDown_2.Items = {'Fine Tree', 'Medium Tree', 'Coarse Tree', 'Optimizable Tree'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'Fine Tree';
            app.DropDown_3 = uidropdown(app.ModelSelectionButtonGroup);
            app.DropDown_3.Items = {'Fine KNN', 'Medium KNN', 'Coarse KNN', 'Cosine KNN', 'Cubic KNN', 'Optimizable KNN'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Enable = 'off';
            app.DropDown_3.Value = 'Fine KNN';
            app.DropDown_4 = uidropdown(app.ModelSelectionButtonGroup);
            app.DropDown_4.Items = {'Linear', 'Cubic', 'Gaussian', 'Optimizable'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'Linear';
            app.DropDown_5 = uidropdown(app.ModelSelectionButtonGroup);
            app.DropDown_5.Items = {'Kernal Naive Bayes'};
            app.DropDown_5.Enable = 'off';
            app.DropDown_5.Value = 'Kernal Naive Bayes';
            app.DropDown_6 = uidropdown(app.ModelSelectionButtonGroup);
            app.DropDown_6.Items = {'Narrow Neural Network', 'Medium Neural Network', 'Wide Neural Network', 'Bilayered Neural Network', 'Trilayered Neural Network'};
            app.DropDown_6.Enable = 'off';
            app.DropDown_6.Value = 'Narrow Neural Network';
            app.ModelNameLabel = uilabel(app.ModelSelectionButtonGroup);
            app.ModelNameLabel.Text = 'Model Name';
            app.ModelTypeLabel = uilabel(app.ModelSelectionButtonGroup);
            app.ModelTypeLabel.Text = 'Model Type';
            app.ModelTrainingLabel = uilabel(app.ModelTrainingTab);
            app.ModelTrainingLabel.Text = 'Model Training';
            app.TrainingDataDetailPanel = uipanel(app.ModelTrainingTab);
            app.TrainingDataDetailPanel.Title = 'Training Data Detail';
            app.DataNumberEditFieldLabel = uilabel(app.TrainingDataDetailPanel);
            app.DataNumberEditFieldLabel.Text = 'Data Number';
            app.DataNumberEditField = uieditfield(app.TrainingDataDetailPanel, 'numeric');
            app.DataNumberEditField.Editable = 'off';
            app.FeatureNumberEditField_2Label = uilabel(app.TrainingDataDetailPanel);
            app.FeatureNumberEditField_2Label.Text = 'Feature Number';
            app.FeatureNumberEditField_2 = uieditfield(app.TrainingDataDetailPanel, 'numeric');
            app.FeatureNumberEditField_2.Limits = [0 Inf];
            app.FeatureNumberEditField_2.Editable = 'off';
            app.ClassNumberEditField_2Label = uilabel(app.TrainingDataDetailPanel);
            app.ClassNumberEditField_2Label.Text = 'Class Number';
            app.ClassNumberEditField_2 = uieditfield(app.TrainingDataDetailPanel, 'numeric');
            app.ClassNumberEditField_2.Editable = 'off';
            app.ClassListListBoxLabel = uilabel(app.TrainingDataDetailPanel);
            app.ClassListListBoxLabel.Text = 'Class List';
            app.ClassListListBox = uilistbox(app.TrainingDataDetailPanel);
            app.ClassListListBox.Enable = 'off';
            app.UITable = uitable(app.TrainingDataDetailPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'; 'Column 13'; 'Column 14'; 'Column 15'};
            app.UITable.RowName = {};
            app.OptimizationOptionPanel = uipanel(app.ModelTrainingTab);
            app.OptimizationOptionPanel.Enable = 'off';
            app.OptimizationOptionPanel.Title = 'Optimization Option';
            app.OptimizerLabel = uilabel(app.OptimizationOptionPanel);
            app.OptimizerLabel.Text = 'Optimizer:';
            app.OptimizerDropDown = uidropdown(app.OptimizationOptionPanel);
            app.OptimizerDropDown.Items = {'Bayesian', 'GridSearch', 'RandomSearch'};
            app.OptimizerDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizerDropDownValueChanged, true);
            app.OptimizerDropDown.Value = 'Bayesian';
            app.AcquisitionfunctionLabel = uilabel(app.OptimizationOptionPanel);
            app.AcquisitionfunctionLabel.Text = 'Acquisition function:';
            app.AcquisitionfunctionDropDown = uidropdown(app.OptimizationOptionPanel);
            app.AcquisitionfunctionDropDown.Items = {'expected-improvement-per-second-plus', 'expected-improvement-per-second', 'expected-improvement-plus', 'expected-improvement', 'lower-confidence-bound', 'probability-of-improvement'};
            app.AcquisitionfunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @AcquisitionfunctionDropDownValueChanged, true);
            app.AcquisitionfunctionDropDown.Value = 'expected-improvement-per-second-plus';
            app.IterationsSpinnerLabel = uilabel(app.OptimizationOptionPanel);
            app.IterationsSpinnerLabel.Text = 'Iterations:';
            app.IterationsSpinner = uispinner(app.OptimizationOptionPanel);
            app.IterationsSpinner.Limits = [0 Inf];
            app.IterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @IterationsSpinnerValueChanged, true);
            app.IterationsSpinner.Value = 30;
            app.TrainedModelInformationPanel = uipanel(app.ModelTrainingTab);
            app.TrainedModelInformationPanel.Title = 'Trained Model Information';
            app.ModelNameEditFieldLabel = uilabel(app.TrainedModelInformationPanel);
            app.ModelNameEditFieldLabel.Text = 'Model Name:';
            app.ModelNameEditField = uieditfield(app.TrainedModelInformationPanel, 'text');
            app.ModelNameEditField.Editable = 'off';
            app.ModelTypeEditFieldLabel = uilabel(app.TrainedModelInformationPanel);
            app.ModelTypeEditFieldLabel.Text = 'Model Type:';
            app.ModelTypeEditField = uieditfield(app.TrainedModelInformationPanel, 'text');
            app.ModelTypeEditField.Editable = 'off';
            app.ValidationTypeEditFieldLabel = uilabel(app.TrainedModelInformationPanel);
            app.ValidationTypeEditFieldLabel.Text = 'Validation Type:';
            app.ValidationTypeEditField = uieditfield(app.TrainedModelInformationPanel, 'text');
            app.ValidationTypeEditField.Editable = 'off';
            app.ExportModelButton = uibutton(app.TrainedModelInformationPanel, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Enable = 'off';
            app.ExportModelButton.Text = 'Export Model';
            app.ValidationAccuracyPercentLabel = uilabel(app.TrainedModelInformationPanel);
            app.ValidationAccuracyPercentLabel.Text = 'Validation Accuracy (Percent):';
            app.ValidationAccuracyPercentEditField = uieditfield(app.TrainedModelInformationPanel, 'numeric');
            app.ValidationAccuracyPercentEditField.Limits = [0 Inf];
            app.ValidationAccuracyPercentEditField.Editable = 'off';
            app.ClassLossEditField_2Label = uilabel(app.TrainedModelInformationPanel);
            app.ClassLossEditField_2Label.Text = 'Class Loss:';
            app.ClassLossEditField_2 = uieditfield(app.TrainedModelInformationPanel, 'numeric');
            app.ClassLossEditField_2.Limits = [0 Inf];
            app.ClassLossEditField_2.Editable = 'off';
            app.FoldsEditFieldLabel = uilabel(app.TrainedModelInformationPanel);
            app.FoldsEditFieldLabel.Text = 'Folds:';
            app.FoldsEditField = uieditfield(app.TrainedModelInformationPanel, 'numeric');
            app.FoldsEditField.Limits = [0 Inf];
            app.FoldsEditField.Editable = 'off';
            app.FoldsEditField.Enable = 'off';
            app.HeldoutEditFieldLabel = uilabel(app.TrainedModelInformationPanel);
            app.HeldoutEditFieldLabel.Text = 'Held out:';
            app.HeldoutEditField = uieditfield(app.TrainedModelInformationPanel, 'numeric');
            app.HeldoutEditField.Limits = [0 Inf];
            app.HeldoutEditField.Editable = 'off';
            app.HeldoutEditField.Enable = 'off';
            app.ClassificationLearnerButton = uibutton(app.ModelTrainingTab, 'push');
            app.ClassificationLearnerButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificationLearnerButtonPushed, true);
            app.ClassificationLearnerButton.Enable = 'off';
            app.ClassificationLearnerButton.Text = 'Classification Learner';
            app.NextButton_3 = uibutton(app.ModelTrainingTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed2, true);
            app.NextButton_3.Text = 'Next';
            app.ModelInformationPanel = uipanel(app.ModelTrainingTab);
            app.ModelInformationPanel.Title = 'Model Information';
            app.TextArea = uitextarea(app.ModelInformationPanel);
            app.TextArea.Editable = 'off';
            app.ModelResultsTab = uitab(app.TabGroup);
            app.ModelResultsTab.Title = 'Model Results';
            app.PredictionResultPlot = uiaxes(app.ModelResultsTab);
            title(app.PredictionResultPlot, 'Title')
            xlabel(app.PredictionResultPlot, 'X')
            ylabel(app.PredictionResultPlot, 'Y')
            zlabel(app.PredictionResultPlot, 'Z')
            app.UIAxes = uiaxes(app.ModelResultsTab);
            title(app.UIAxes, 'ROC for Classification')
            xlabel(app.UIAxes, 'False positive rate')
            ylabel(app.UIAxes, 'True positive rate')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1 1.2];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'; '1.2'};
            app.ClassificationResultsLabel = uilabel(app.ModelResultsTab);
            app.ClassificationResultsLabel.Text = 'Classification Results';
            app.ResultTable = uitable(app.ModelResultsTab);
            app.ResultTable.ColumnName = '';
            app.ResultTable.RowName = {};
            app.ResultLabel = uilabel(app.ModelResultsTab);
            app.ResultLabel.Text = 'Result';
            app.Preprocessing_ExportButton_2 = uibutton(app.ModelResultsTab, 'push');
            app.Preprocessing_ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_ExportButton_2Pushed, true);
            app.Preprocessing_ExportButton_2.Enable = 'off';
            app.Preprocessing_ExportButton_2.Text = 'Predict';
            app.Preprocessing_ExportButton_3 = uibutton(app.ModelResultsTab, 'push');
            app.Preprocessing_ExportButton_3.ButtonPushedFcn = createCallbackFcn(app, @Preprocessing_ExportButton_3Pushed, true);
            app.Preprocessing_ExportButton_3.Text = 'Next';
            app.TestAccuracyPercentEditFieldLabel = uilabel(app.ModelResultsTab);
            app.TestAccuracyPercentEditFieldLabel.Text = 'Test Accuracy(Percent):';
            app.TestAccuracyPercentEditField = uieditfield(app.ModelResultsTab, 'numeric');
            app.TestAccuracyPercentEditField.Limits = [0 Inf];
            app.TestAccuracyPercentEditField.Editable = 'off';
            app.TestDataSet = uilabel(app.ModelResultsTab);
            app.TestDataSet.Text = 'Test Data Set';
            app.UITable2 = uitable(app.ModelResultsTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'; 'Column 13'; 'Column 14'; 'Column 15'};
            app.UITable2.RowName = {};
            app.ROCcurveLabel = uilabel(app.ModelResultsTab);
            app.ROCcurveLabel.Text = 'ROC curve';
            app.PositiveClassLabel = uilabel(app.ModelResultsTab);
            app.PositiveClassLabel.Text = 'Positive Class:';
            app.PositiveClassDropDown = uidropdown(app.ModelResultsTab);
            app.AUCEditFieldLabel = uilabel(app.ModelResultsTab);
            app.AUCEditFieldLabel.Text = 'AUC:';
            app.AUCEditField = uieditfield(app.ModelResultsTab, 'numeric');
            app.ProjectInfoTab = uitab(app.TabGroup);
            app.ProjectInfoTab.Title = 'Project Info';
            app.AboutYourProjectPanel = uipanel(app.ProjectInfoTab);
            app.AboutYourProjectPanel.Title = 'About Your Project';
            app.ProjectNameEditField_2Label = uilabel(app.AboutYourProjectPanel);
            app.ProjectNameEditField_2Label.Text = 'Project Name';
            app.ProjectInfo_ProjectNameEditField = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_ProjectNameEditField.Editable = 'off';
            app.ImportedFileEditFieldLabel = uilabel(app.AboutYourProjectPanel);
            app.ImportedFileEditFieldLabel.Text = 'Imported File';
            app.ProjectInfo_ImportedFileEditField = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_ImportedFileEditField.Editable = 'off';
            app.DescriptionTextAreaLabel = uilabel(app.AboutYourProjectPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.ProjectInfo_DescriptionTextArea = uitextarea(app.AboutYourProjectPanel);
            app.ProjectInfo_DescriptionTextArea.Editable = 'off';
            app.EditButton = uibutton(app.AboutYourProjectPanel, 'state');
            app.EditButton.ValueChangedFcn = createCallbackFcn(app, @EditButtonValueChanged, true);
            app.EditButton.Text = 'Edit';
            app.ReportGenerationPanel = uipanel(app.AboutYourProjectPanel);
            app.ReportGenerationPanel.Title = 'Report Generation';
            app.PeakDetectionCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.PeakDetectionCheckBox.Enable = 'off';
            app.PeakDetectionCheckBox.Text = 'Peak Detection';
            app.NormalizationCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.NormalizationCheckBox.Enable = 'off';
            app.NormalizationCheckBox.Text = 'Normalization';
            app.PreprocessingCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.PreprocessingCheckBox.Enable = 'off';
            app.PreprocessingCheckBox.Text = 'Pre-processing';
            app.GeneratePDFReportButton = uibutton(app.ReportGenerationPanel, 'push');
            app.GeneratePDFReportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFReportButtonPushed, true);
            app.GeneratePDFReportButton.Text = 'Generate PDF Report';
            app.PeakBinningCheckBox = uicheckbox(app.ReportGenerationPanel);
            app.PeakBinningCheckBox.Enable = 'off';
            app.PeakBinningCheckBox.Text = 'Peak Binning';
            app.StepsLabel = uilabel(app.ReportGenerationPanel);
            app.StepsLabel.Text = 'Steps';
            app.CreatedLabel = uilabel(app.AboutYourProjectPanel);
            app.CreatedLabel.Text = 'Created';
            app.ProjectInfo_CreatedDate = uieditfield(app.AboutYourProjectPanel, 'text');
            app.ProjectInfo_CreatedDate.Editable = 'off';
            app.DataExportPanel = uipanel(app.AboutYourProjectPanel);
            app.DataExportPanel.Title = 'Data Export';
            app.Export_PeakDetectionCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_PeakDetectionCheckBox.Enable = 'off';
            app.Export_PeakDetectionCheckBox.Text = 'Peak Detection';
            app.Export_NormalizationCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_NormalizationCheckBox.Enable = 'off';
            app.Export_NormalizationCheckBox.Text = 'Normalization';
            app.Export_PreprocessingCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_PreprocessingCheckBox.Enable = 'off';
            app.Export_PreprocessingCheckBox.Text = 'Pre-processing';
            app.ExportCSVFilesButton = uibutton(app.DataExportPanel, 'push');
            app.ExportCSVFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVFilesButtonPushed, true);
            app.ExportCSVFilesButton.Text = 'Export CSV Files';
            app.Export_PeakBinningCheckBox = uicheckbox(app.DataExportPanel);
            app.Export_PeakBinningCheckBox.Enable = 'off';
            app.Export_PeakBinningCheckBox.Text = 'Peak Binning';
            app.StepsLabel_2 = uilabel(app.DataExportPanel);
            app.StepsLabel_2.Text = 'Steps';
            app.Export_MLPreprocessingCheckBox_2 = uicheckbox(app.DataExportPanel);
            app.Export_MLPreprocessingCheckBox_2.Enable = 'off';
            app.Export_MLPreprocessingCheckBox_2.Text = 'ML Data Pre-processing';
            app.Export_PreprocessingCheckBox_3 = uicheckbox(app.DataExportPanel);
            app.Export_PreprocessingCheckBox_3.Enable = 'off';
            app.Export_PreprocessingCheckBox_3.Text = 'ML Data Spliting';
            app.ExportPreProcessedSettingsButton = uibutton(app.AboutYourProjectPanel, 'push');
            app.ExportPreProcessedSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPreProcessedSettingsButtonPushed, true);
            app.ExportPreProcessedSettingsButton.Text = 'Export Pre-Processed Settings';
            app.Image2 = uiimage(app.ProjectInfoTab);
            app.Image2.ImageSource = 'Mspec_Application.png';
            app.AboutMSpecLabel = uilabel(app.ProjectInfoTab);
            app.AboutMSpecLabel.Text = 'About MSpec';
            app.Label_4 = uilabel(app.ProjectInfoTab);
            app.Label_4.Text = {'MSpec is an all-in-one solution for spectral data analysis.'; 'The application features tools such as pre-processing, normalization, peak binning,'; 'and classification to aid the user in the analysis of spectral data.'; 'The function parameters are also customizable to fit the user''s preferences.'; 'The processed data from each tool can also be exported as csv files and a final report'; 'of each project can also be generated and exported as pdf files.'; ''; 'For more information, please consult the MSpec documentation. '; 'If you need further assistance, please contact us via our email.'};
            app.Hyperlink = uihyperlink(app.ProjectInfoTab);
            app.Hyperlink.URL = 'https://github.com/chayudS/MSpec-Spectral-Preprocessing-and-Analysis-Environment.git';
            app.Hyperlink.Text = 'Documentation';
            app.ClassNumberDropDown_2Label = uilabel(app.MSPECAppUIFigure);
            app.ClassNumberDropDown_2Label.Visible = 'off';
            app.ClassNumberDropDown_2Label.Text = 'Class Number';
            app.ClassNumberDropDown_2 = uidropdown(app.MSPECAppUIFigure);
            app.ClassNumberDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ClassNumberDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ClassNumberDropDown_2ValueChanged, true);
            app.ClassNumberDropDown_2.Visible = 'off';
            app.ClassNumberDropDown_2.Value = '1';
            app.ScoreLabel = uilabel(app.MSPECAppUIFigure);
            app.ScoreLabel.Text = 'Score';
            app.ScoreTable = uitable(app.MSPECAppUIFigure);
            app.ScoreTable.ColumnName = '';
            app.ScoreTable.RowName = {};
            % Show the figure after all components are created
            app.MSPECAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSpecMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSPECAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSPECAppUIFigure)
        end
    end
end