classdef ModelImportApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportModelUIFigure             matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ModelTab                        matlab.ui.container.Tab
        ModelFileLabel                  matlab.ui.control.Label
        ModelLabel                      matlab.ui.control.Label
        ClassificationModelLabel        matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        ModelTypeDropDown               matlab.ui.control.DropDown
        ModelTypeDropDownLabel          matlab.ui.control.Label
        ModelNameEditField              matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        ModelDescriptionLabel           matlab.ui.control.Label
        ModelDescriptionTextArea        matlab.ui.control.TextArea
        ImportButton                    matlab.ui.control.Button
        DataTab                         matlab.ui.container.Tab
        ImportModelButton               matlab.ui.control.Button
        ImportSettingsButton            matlab.ui.control.Button
        PeakBinningPanel                matlab.ui.container.Panel
        NoofEdgesEditField              matlab.ui.control.NumericEditField
        NoofEdgesEditFieldLabel         matlab.ui.control.Label
        ImportBinEdgesLabel             matlab.ui.control.Label
        Binning_FileLabel               matlab.ui.control.Label
        Binning_ImportEdgeButton        matlab.ui.control.Button
        NormalizationMethodsButtonGroup  matlab.ui.container.ButtonGroup
        Normalization_MaxIntensityButton  matlab.ui.control.ToggleButton
        Normalization_ReferenceMZEditField  matlab.ui.control.NumericEditField
        ReferenceMZLabel                matlab.ui.control.Label
        Normalization_NoiseLevelButton  matlab.ui.control.ToggleButton
        Normalization_MedianButton      matlab.ui.control.ToggleButton
        Normalization_pnormButton       matlab.ui.control.ToggleButton
        Normalization_pvalueSpinner     matlab.ui.control.Spinner
        pvalueSpinnerLabel              matlab.ui.control.Label
        Normalization_ReferencePeakButton  matlab.ui.control.ToggleButton
        Normalization_ioncountsButton   matlab.ui.control.ToggleButton
        SectionofInterestPanel          matlab.ui.container.Panel
        Preprocessing_EndingpointEditField  matlab.ui.control.EditField
        EndingpointEditFieldLabel       matlab.ui.control.Label
        Preprocessing_StartingpointEditField  matlab.ui.control.EditField
        StartingpointEditFieldLabel     matlab.ui.control.Label
        PeakAlignmentPanel              matlab.ui.container.Panel
        MaximumshiftallowedEditFieldLabel  matlab.ui.control.Label
        Preprocessing_MaximumshiftallowedEditField  matlab.ui.control.NumericEditField
        MinimumsegementsizeallowedLabel  matlab.ui.control.Label
        Preprocessing_MinimumsegementsizeallowedEditField  matlab.ui.control.NumericEditField
        ReferenceSpectrumEditFieldLabel  matlab.ui.control.Label
        Preprocessing_ReferenceSpectrumEditField  matlab.ui.control.NumericEditField
        BaselineCorrectionPanel_2       matlab.ui.container.Panel
        QuantilevalueEditFieldLabel_2   matlab.ui.control.Label
        Preprocessing_QuantilevalueEditField  matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel_2        matlab.ui.control.Label
        Preprocessing_StepsizeEditField  matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel_2      matlab.ui.control.Label
        Preprocessing_WindowSizeEditField  matlab.ui.control.NumericEditField
        PreprocessingParametersLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
           CallingApp % Main app object
           ImportedModel % model
           ImportedEdgeList
           ImportedEdgeListFileName
           PreprocessingSetting PreprocessingParameters
           ToImportPath = './models/KNN/';
           ParentNode;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.ToImportPath = './models/KNN/';
            app.ParentNode = app.CallingApp.KNEARESTNEIGHBORKNNNode;
        end

        % Button pushed function: ImportModelButton
        function ImportModelButtonPushed(app, event)
            % Call main app's public function
            
            d = uiprogressdlg(app.ImportModelUIFigure,'Title','Importing the Model',...
            'Indeterminate','on');
            drawnow
            
            % save file
            MSpecAnalysisController.importModelToApp(app);
            
            % find path file
            modelName = app.ModelNameEditField.Value;
            modelFileName = strcat(modelName,'.mat');
            modelfullFileName = strcat(app.ToImportPath,modelFileName);
            
            % add new node to ui tree
            uitreenode(app.ParentNode,'Text',modelName,'NodeData',[modelfullFileName]);
            
            % Delete the dialog box  
            close(d)
            delete(app)       
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            MSpecAnalysisController.importModel(app);
        end

        % Value changed function: ModelTypeDropDown
        function ModelTypeDropDownValueChanged(app, event)
            value = app.ModelTypeDropDown.Value;
            switch value
                case 'K-Nearest Neighbor (KNN)'
                    app.ToImportPath = './models/KNN/';
                    app.ParentNode = app.CallingApp.KNEARESTNEIGHBORKNNNode;
                case 'Support Vector Machine (SVM)'
                    app.ToImportPath = './models/SVM/';
                    app.ParentNode = app.CallingApp.SUPPORTVECTORMACHINESSVMNode;
                case 'Decision Tree'
                    app.ToImportPath = './models/Decision Tree/';
                    app.ParentNode = app.CallingApp.DECISIONTREESNode;
                case 'Naive Bayes Classifier'
                    app.ToImportPath = './models/Naive Bayes/';
                    app.ParentNode = app.CallingApp.NAIVEBAYESCLASSIFIERSNode;
                case 'Ensemble'
                    app.ToImportPath = './models/Ensemble/';
                    app.ParentNode = app.CallingApp.ENSEMBLEBaggedTreeNode;
                case 'Linear Discriminant Analysis (LDA)'
                    app.ToImportPath = './models/LDA/';
                    app.ParentNode = app.CallingApp.LINEARDISCRIINANTANALYSISLDANode;
                otherwise
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DataTab;
        end

        % Button pushed function: Binning_ImportEdgeButton
        function Binning_ImportEdgeButtonPushed(app, event)
            MSpecAnalysisController.importBinEdges(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportModelUIFigure = uifigure('Visible', 'off');
            app.ImportModelUIFigure.Name = 'Import Model';
            app.ImportModelUIFigure.Icon = 'icons8-downloading-updates-80.png';
            app.TabGroup = uitabgroup(app.ImportModelUIFigure);
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.ImportButton = uibutton(app.ModelTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ModelDescriptionTextArea = uitextarea(app.ModelTab);
            app.ModelDescriptionTextArea.Enable = 'off';
            app.ModelDescriptionLabel = uilabel(app.ModelTab);
            app.ModelDescriptionLabel.Enable = 'off';
            app.ModelDescriptionLabel.Text = 'Model Description';
            app.ModelNameEditFieldLabel = uilabel(app.ModelTab);
            app.ModelNameEditFieldLabel.Enable = 'off';
            app.ModelNameEditFieldLabel.Text = 'Model Name';
            app.ModelNameEditField = uieditfield(app.ModelTab, 'text');
            app.ModelNameEditField.Enable = 'off';
            app.ModelTypeDropDownLabel = uilabel(app.ModelTab);
            app.ModelTypeDropDownLabel.Enable = 'off';
            app.ModelTypeDropDownLabel.Text = 'Model Type';
            app.ModelTypeDropDown = uidropdown(app.ModelTab);
            app.ModelTypeDropDown.Items = {'K-Nearest Neighbor (KNN)', 'Support Vector Machine (SVM)', 'Decision Tree', 'Naive Bayes Classifier', 'Ensemble', 'Linear Discriminant Analysis (LDA)'};
            app.ModelTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelTypeDropDownValueChanged, true);
            app.ModelTypeDropDown.Enable = 'off';
            app.ModelTypeDropDown.Value = 'K-Nearest Neighbor (KNN)';
            app.NextButton = uibutton(app.ModelTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.ClassificationModelLabel = uilabel(app.ModelTab);
            app.ClassificationModelLabel.Text = 'Classification Model';
            app.ModelLabel = uilabel(app.ModelTab);
            app.ModelLabel.Text = 'Model';
            app.ModelFileLabel = uilabel(app.ModelTab);
            app.ModelFileLabel.Text = 'Choose File . . .';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.PreprocessingParametersLabel = uilabel(app.DataTab);
            app.PreprocessingParametersLabel.Text = 'Preprocessing Parameters';
            app.BaselineCorrectionPanel_2 = uipanel(app.DataTab);
            app.BaselineCorrectionPanel_2.Title = 'Baseline Correction';
            app.Preprocessing_WindowSizeEditField = uieditfield(app.BaselineCorrectionPanel_2, 'numeric');
            app.Preprocessing_WindowSizeEditField.Value = 50;
            app.WindowSizeEditFieldLabel_2 = uilabel(app.BaselineCorrectionPanel_2);
            app.WindowSizeEditFieldLabel_2.Text = 'Window Size';
            app.Preprocessing_StepsizeEditField = uieditfield(app.BaselineCorrectionPanel_2, 'numeric');
            app.Preprocessing_StepsizeEditField.Value = 50;
            app.StepsizeEditFieldLabel_2 = uilabel(app.BaselineCorrectionPanel_2);
            app.StepsizeEditFieldLabel_2.Text = 'Step size';
            app.Preprocessing_QuantilevalueEditField = uieditfield(app.BaselineCorrectionPanel_2, 'numeric');
            app.Preprocessing_QuantilevalueEditField.Limits = [0 Inf];
            app.Preprocessing_QuantilevalueEditField.Value = 0.5;
            app.QuantilevalueEditFieldLabel_2 = uilabel(app.BaselineCorrectionPanel_2);
            app.QuantilevalueEditFieldLabel_2.Text = 'Quantile value';
            app.PeakAlignmentPanel = uipanel(app.DataTab);
            app.PeakAlignmentPanel.Title = 'Peak Alignment';
            app.Preprocessing_ReferenceSpectrumEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_ReferenceSpectrumEditField.Value = 2;
            app.ReferenceSpectrumEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.ReferenceSpectrumEditFieldLabel.Text = 'Reference Spectrum';
            app.Preprocessing_MinimumsegementsizeallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MinimumsegementsizeallowedEditField.Value = 10;
            app.MinimumsegementsizeallowedLabel = uilabel(app.PeakAlignmentPanel);
            app.MinimumsegementsizeallowedLabel.Text = {'Minimum segement'; 'size allowed'};
            app.Preprocessing_MaximumshiftallowedEditField = uieditfield(app.PeakAlignmentPanel, 'numeric');
            app.Preprocessing_MaximumshiftallowedEditField.Value = 50;
            app.MaximumshiftallowedEditFieldLabel = uilabel(app.PeakAlignmentPanel);
            app.MaximumshiftallowedEditFieldLabel.Text = 'Maximum shift allowed';
            app.SectionofInterestPanel = uipanel(app.DataTab);
            app.SectionofInterestPanel.Title = 'Section of Interest';
            app.StartingpointEditFieldLabel = uilabel(app.SectionofInterestPanel);
            app.StartingpointEditFieldLabel.Text = 'Starting point';
            app.Preprocessing_StartingpointEditField = uieditfield(app.SectionofInterestPanel, 'text');
            app.EndingpointEditFieldLabel = uilabel(app.SectionofInterestPanel);
            app.EndingpointEditFieldLabel.Text = 'Ending point';
            app.Preprocessing_EndingpointEditField = uieditfield(app.SectionofInterestPanel, 'text');
            app.NormalizationMethodsButtonGroup = uibuttongroup(app.DataTab);
            app.NormalizationMethodsButtonGroup.Title = 'Normalization Methods';
            app.Normalization_ioncountsButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_ioncountsButton.Text = 'ion counts';
            app.Normalization_ioncountsButton.Value = true;
            app.Normalization_ReferencePeakButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_ReferencePeakButton.Text = 'Reference Peak';
            app.pvalueSpinnerLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.pvalueSpinnerLabel.Enable = 'off';
            app.pvalueSpinnerLabel.Text = 'p-value';
            app.Normalization_pvalueSpinner = uispinner(app.NormalizationMethodsButtonGroup);
            app.Normalization_pvalueSpinner.Limits = [1 Inf];
            app.Normalization_pvalueSpinner.Enable = 'off';
            app.Normalization_pvalueSpinner.Value = 1;
            app.Normalization_pnormButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_pnormButton.Text = 'p-norm';
            app.Normalization_MedianButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_MedianButton.Text = 'Median';
            app.Normalization_NoiseLevelButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_NoiseLevelButton.Text = 'Noise Level';
            app.ReferenceMZLabel = uilabel(app.NormalizationMethodsButtonGroup);
            app.ReferenceMZLabel.Enable = 'off';
            app.ReferenceMZLabel.Text = {'Reference'; 'M/Z'};
            app.Normalization_ReferenceMZEditField = uieditfield(app.NormalizationMethodsButtonGroup, 'numeric');
            app.Normalization_ReferenceMZEditField.ValueDisplayFormat = '%5.4f';
            app.Normalization_ReferenceMZEditField.Editable = 'off';
            app.Normalization_ReferenceMZEditField.Enable = 'off';
            app.Normalization_MaxIntensityButton = uitogglebutton(app.NormalizationMethodsButtonGroup);
            app.Normalization_MaxIntensityButton.Text = 'Maximum Intensity';
            app.PeakBinningPanel = uipanel(app.DataTab);
            app.PeakBinningPanel.Title = 'Peak Binning';
            app.Binning_ImportEdgeButton = uibutton(app.PeakBinningPanel, 'push');
            app.Binning_ImportEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @Binning_ImportEdgeButtonPushed, true);
            app.Binning_ImportEdgeButton.Icon = 'file_icon.png';
            app.Binning_ImportEdgeButton.Text = '';
            app.Binning_FileLabel = uilabel(app.PeakBinningPanel);
            app.Binning_FileLabel.Text = 'Choose File . . .';
            app.ImportBinEdgesLabel = uilabel(app.PeakBinningPanel);
            app.ImportBinEdgesLabel.Text = 'Import Bin Edges';
            app.NoofEdgesEditFieldLabel = uilabel(app.PeakBinningPanel);
            app.NoofEdgesEditFieldLabel.Text = 'No. of Edges';
            app.NoofEdgesEditField = uieditfield(app.PeakBinningPanel, 'numeric');
            app.NoofEdgesEditField.Editable = 'off';
            app.ImportSettingsButton = uibutton(app.DataTab, 'push');
            app.ImportSettingsButton.Icon = 'Open.png';
            app.ImportSettingsButton.Enable = 'off';
            app.ImportSettingsButton.Text = 'Import Settings';
            app.ImportModelButton = uibutton(app.DataTab, 'push');
            app.ImportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportModelButtonPushed, true);
            app.ImportModelButton.Enable = 'off';
            app.ImportModelButton.Text = 'Import Model';
            % Show the figure after all components are created
            app.ImportModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelImportApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportModelUIFigure)
        end
    end
end